classdef signalgenerator_12_13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TextArea_2              matlab.ui.control.TextArea
        Label_129               matlab.ui.control.Label
        Lamp_8                  matlab.ui.control.Lamp
        Lamp_8Label             matlab.ui.control.Label
        GPIOPanel               matlab.ui.container.Panel
        Button_34               matlab.ui.control.Button
        HLallselect             matlab.ui.control.CheckBox
        HLCheckBox_8            matlab.ui.control.CheckBox
        HLCheckBox_7            matlab.ui.control.CheckBox
        HLCheckBox_6            matlab.ui.control.CheckBox
        HLCheckBox_5            matlab.ui.control.CheckBox
        HLCheckBox_4            matlab.ui.control.CheckBox
        HLCheckBox_3            matlab.ui.control.CheckBox
        HLCheckBox_2            matlab.ui.control.CheckBox
        HLCheckBox              matlab.ui.control.CheckBox
        directionallselect      matlab.ui.control.CheckBox
        OutInCheckBox_8         matlab.ui.control.CheckBox
        OutInCheckBox_7         matlab.ui.control.CheckBox
        OutInCheckBox_6         matlab.ui.control.CheckBox
        OutInCheckBox_5         matlab.ui.control.CheckBox
        OutInCheckBox_4         matlab.ui.control.CheckBox
        OutInCheckBox_3         matlab.ui.control.CheckBox
        OutInCheckBox_2         matlab.ui.control.CheckBox
        OutInCheckBox           matlab.ui.control.CheckBox
        Gpioallselect           matlab.ui.control.CheckBox
        Gpio7CheckBox           matlab.ui.control.CheckBox
        Gpio6CheckBox           matlab.ui.control.CheckBox
        Gpio5CheckBox           matlab.ui.control.CheckBox
        Gpio4CheckBox           matlab.ui.control.CheckBox
        Gpio3CheckBox           matlab.ui.control.CheckBox
        Gpio2CheckBox           matlab.ui.control.CheckBox
        Gpio1CheckBox           matlab.ui.control.CheckBox
        Gpio0CheckBox           matlab.ui.control.CheckBox
        HLabel                  matlab.ui.control.Label
        outLabel                matlab.ui.control.Label
        Label_97                matlab.ui.control.Label
        EditField_21            matlab.ui.control.NumericEditField
        EditField_21Label       matlab.ui.control.Label
        ButtonGroup             matlab.ui.container.ButtonGroup
        Button_33               matlab.ui.control.RadioButton
        Button_32               matlab.ui.control.RadioButton
        Button_31               matlab.ui.control.RadioButton
        MHzLabel_49             matlab.ui.control.Label
        fs_12                   matlab.ui.control.NumericEditField
        Label_105               matlab.ui.control.Label
        Fc_6                    matlab.ui.control.NumericEditField
        Label_88                matlab.ui.control.Label
        samples_6               matlab.ui.control.NumericEditField
        Label_83                matlab.ui.control.Label
        f                       matlab.ui.control.NumericEditField
        Label_82                matlab.ui.control.Label
        MHzLabel_45             matlab.ui.control.Label
        HzLabel                 matlab.ui.control.Label
        Panel_37                matlab.ui.container.Panel
        CheckBox_4              matlab.ui.control.CheckBox
        EditField_15            matlab.ui.control.NumericEditField
        Label_96                matlab.ui.control.Label
        MHzLabel_48             matlab.ui.control.Label
        Panel_31                matlab.ui.container.Panel
        samplesLabel_4          matlab.ui.control.Label
        samples_4               matlab.ui.control.NumericEditField
        Label_59                matlab.ui.control.Label
        Panel_30                matlab.ui.container.Panel
        MHzLabel_34             matlab.ui.control.Label
        fsam_rx_3               matlab.ui.control.NumericEditField
        Label_56                matlab.ui.control.Label
        Panel_29                matlab.ui.container.Panel
        dBLabel_6               matlab.ui.control.Label
        RXgain_3                matlab.ui.control.NumericEditField
        EditField_9Label_11     matlab.ui.control.Label
        Panel_20                matlab.ui.container.Panel
        samples_7               matlab.ui.control.NumericEditField
        Label_86                matlab.ui.control.Label
        sLabel_11               matlab.ui.control.Label
        Button_28               matlab.ui.control.Button
        Panel_18                matlab.ui.container.Panel
        fs_7                    matlab.ui.control.NumericEditField
        Label_57                matlab.ui.control.Label
        MHzLabel_35             matlab.ui.control.Label
        SDRPlatform_rx_3        matlab.ui.control.TextArea
        SDR_4                   matlab.ui.control.Label
        Button_27               matlab.ui.control.Button
        Panel_4                 matlab.ui.container.Panel
        Panel_6                 matlab.ui.container.Panel
        CheckBox_3              matlab.ui.control.CheckBox
        Label_94                matlab.ui.control.Label
        EditField_3             matlab.ui.control.NumericEditField
        Label_8                 matlab.ui.control.Label
        EditField_2             matlab.ui.control.NumericEditField
        Label_7                 matlab.ui.control.Label
        EditField               matlab.ui.control.NumericEditField
        Label_6                 matlab.ui.control.Label
        dBmLabel_3              matlab.ui.control.Label
        dBmLabel_2              matlab.ui.control.Label
        dBmLabel                matlab.ui.control.Label
        dBmLabel_4              matlab.ui.control.Label
        dBLabel                 matlab.ui.control.Label
        TXgain                  matlab.ui.control.NumericEditField
        EditField_9Label_5      matlab.ui.control.Label
        AMPanel_4               matlab.ui.container.Panel
        EditField_amfs          matlab.ui.control.NumericEditField
        Label_10                matlab.ui.control.Label
        MHzLabel_3              matlab.ui.control.Label
        Panel_8                 matlab.ui.container.Panel
        TxDuration              matlab.ui.control.NumericEditField
        Label_104               matlab.ui.control.Label
        MsaLabel                matlab.ui.control.Label
        SDRAddress_tx           matlab.ui.control.EditField
        EditField_9Label_2      matlab.ui.control.Label
        MsasLabel               matlab.ui.control.Label
        masterclock             matlab.ui.control.NumericEditField
        Label_9                 matlab.ui.control.Label
        Button_14               matlab.ui.control.Button
        ButtonGroup_tx          matlab.ui.container.ButtonGroup
        CMButton                matlab.ui.control.RadioButton
        PMButton_tx             matlab.ui.control.RadioButton
        FMButton_tx             matlab.ui.control.RadioButton
        AMButton_tx             matlab.ui.control.RadioButton
        Panel_5                 matlab.ui.container.Panel
        SDRFc_tx                matlab.ui.control.NumericEditField
        EditField_9Label_3      matlab.ui.control.Label
        MHzLabel                matlab.ui.control.Label
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        Panel_43                matlab.ui.container.Panel
        CheckBox_17             matlab.ui.control.CheckBox
        CheckBox_16             matlab.ui.control.CheckBox
        dBEditField_7           matlab.ui.control.NumericEditField
        dBEditField_7Label      matlab.ui.control.Label
        EditField_22            matlab.ui.control.NumericEditField
        Label_128               matlab.ui.control.Label
        dBEditField             matlab.ui.control.NumericEditField
        Label_107               matlab.ui.control.Label
        Panel_48                matlab.ui.container.Panel
        CheckBox_10             matlab.ui.control.CheckBox
        pulsewidth              matlab.ui.control.NumericEditField
        PRILabel_2              matlab.ui.control.Label
        PRI_tx                  matlab.ui.control.NumericEditField
        PRILabel                matlab.ui.control.Label
        Panel_49                matlab.ui.container.Panel
        ffm_3                   matlab.ui.control.NumericEditField
        Label_103               matlab.ui.control.Label
        CheckBox_9              matlab.ui.control.CheckBox
        MHzEditField            matlab.ui.control.NumericEditField
        MHzEditFieldLabel       matlab.ui.control.Label
        Panel_47                matlab.ui.container.Panel
        CheckBox_8              matlab.ui.control.CheckBox
        fam_2                   matlab.ui.control.NumericEditField
        Label_102               matlab.ui.control.Label
        fam                     matlab.ui.control.NumericEditField
        Label_11                matlab.ui.control.Label
        dBEditField_3           matlab.ui.control.NumericEditField
        dBLabel_8               matlab.ui.control.Label
        ALCCheckBox             matlab.ui.control.CheckBox
        Panel_45                matlab.ui.container.Panel
        sEditField              matlab.ui.control.NumericEditField
        sEditFieldLabel         matlab.ui.control.Label
        Button_45               matlab.ui.control.Button
        Lamp_3                  matlab.ui.control.Lamp
        ALCDropDown             matlab.ui.control.DropDown
        ALCDropDownLabel        matlab.ui.control.Label
        Button_starttransmit    matlab.ui.control.Switch
        Label_101               matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        Label_22                matlab.ui.control.Label
        Panel_46                matlab.ui.container.Panel
        CheckBox_15             matlab.ui.control.CheckBox
        stepf_tx_2              matlab.ui.control.NumericEditField
        PRILabel_6              matlab.ui.control.Label
        stepf_tx                matlab.ui.control.NumericEditField
        PRILabel_5              matlab.ui.control.Label
        Stopf                   matlab.ui.control.NumericEditField
        PRILabel_4              matlab.ui.control.Label
        starf_tx                matlab.ui.control.NumericEditField
        PRILabel_3              matlab.ui.control.Label
        Image6                  matlab.ui.control.Image
        Tab_3                   matlab.ui.container.Tab
        Panel_50                matlab.ui.container.Panel
        CheckBox_19             matlab.ui.control.CheckBox
        CheckBox_18             matlab.ui.control.CheckBox
        EditField_23            matlab.ui.control.NumericEditField
        EditField_23Label       matlab.ui.control.Label
        ButtonGroup_2           matlab.ui.container.ButtonGroup
        Button_41               matlab.ui.control.RadioButton
        Button_40               matlab.ui.control.RadioButton
        Button_39               matlab.ui.control.RadioButton
        rb                      matlab.ui.control.NumericEditField
        Label_28                matlab.ui.control.Label
        DropDown_3              matlab.ui.control.DropDown
        Label_29                matlab.ui.control.Label
        MSasEditField           matlab.ui.control.NumericEditField
        Label_113               matlab.ui.control.Label
        rolloff                 matlab.ui.control.NumericEditField
        Label_112               matlab.ui.control.Label
        EditField_19            matlab.ui.control.EditField
        Label_111               matlab.ui.control.Label
        dBEditField_6           matlab.ui.control.NumericEditField
        dBEditField_6Label      matlab.ui.control.Label
        ALCCheckBox_2           matlab.ui.control.CheckBox
        dBEditField_5           matlab.ui.control.NumericEditField
        dBEditField_5Label      matlab.ui.control.Label
        dBEditField_4           matlab.ui.control.NumericEditField
        dBEditField_4Label      matlab.ui.control.Label
        Panel_51                matlab.ui.container.Panel
        Button_46               matlab.ui.control.Button
        Lamp_5                  matlab.ui.control.Lamp
        ALCDropDown_2           matlab.ui.control.DropDown
        ALCDropDown_2Label      matlab.ui.control.Label
        Button_starttransmit_4  matlab.ui.control.Switch
        Label_109               matlab.ui.control.Label
        DropDown_7              matlab.ui.control.DropDown
        DropDown_7Label         matlab.ui.control.Label
        Image7                  matlab.ui.control.Image
        Tab_6                   matlab.ui.container.Tab
        Panel_52                matlab.ui.container.Panel
        lowpassfilter           matlab.ui.control.NumericEditField
        Label_95                matlab.ui.control.Label
        CheckBox_5              matlab.ui.control.CheckBox
        TabGroup2               matlab.ui.container.TabGroup
        Tab_7                   matlab.ui.container.Tab
        fc_12                   matlab.ui.control.NumericEditField
        Label_53                matlab.ui.control.Label
        MHzLabel_33             matlab.ui.control.Label
        Tab_8                   matlab.ui.container.Tab
        tonenum                 matlab.ui.control.NumericEditField
        Label_51                matlab.ui.control.Label
        MHzLabel_30             matlab.ui.control.Label
        endfd                   matlab.ui.control.NumericEditField
        Label_50                matlab.ui.control.Label
        startfd                 matlab.ui.control.NumericEditField
        Label_49                matlab.ui.control.Label
        MHzLabel_29             matlab.ui.control.Label
        Tab_9                   matlab.ui.container.Tab
        usLabel_4               matlab.ui.control.Label
        pulsewidth1             matlab.ui.control.NumericEditField
        Label_66                matlab.ui.control.Label
        pri1                    matlab.ui.control.NumericEditField
        Label_65                matlab.ui.control.Label
        msLabel_2               matlab.ui.control.Label
        FMCWTab                 matlab.ui.container.Tab
        rolloff_5               matlab.ui.control.NumericEditField
        Label_70                matlab.ui.control.Label
        sweeptime               matlab.ui.control.NumericEditField
        Label_69                matlab.ui.control.Label
        MHzLabel_43             matlab.ui.control.Label
        msLabel_3               matlab.ui.control.Label
        BPSKTab                 matlab.ui.container.Tab
        rolloff_3               matlab.ui.control.NumericEditField
        Label_62                matlab.ui.control.Label
        rb_3                    matlab.ui.control.NumericEditField
        Label_61                matlab.ui.control.Label
        MBdLabel_3              matlab.ui.control.Label
        Tab_15                  matlab.ui.container.Tab
        lowpassfilter_3         matlab.ui.control.NumericEditField
        Label_126               matlab.ui.control.Label
        lowpassfilter_2         matlab.ui.control.NumericEditField
        Label_125               matlab.ui.control.Label
        samples_3               matlab.ui.control.NumericEditField
        Label_55                matlab.ui.control.Label
        Panel_56                matlab.ui.container.Panel
        CheckBox_13             matlab.ui.control.CheckBox
        DropDown_13             matlab.ui.control.DropDown
        Label_81                matlab.ui.control.Label
        Panel_53                matlab.ui.container.Panel
        fc_11                   matlab.ui.control.NumericEditField
        Label_52                matlab.ui.control.Label
        MHzLabel_32             matlab.ui.control.Label
        Lamp_6                  matlab.ui.control.Lamp
        Button_starttransmit_5  matlab.ui.control.Switch
        Label_116               matlab.ui.control.Label
        DropDown_9              matlab.ui.control.DropDown
        DropDown_9Label         matlab.ui.control.Label
        CheckBox_12             matlab.ui.control.CheckBox
        Image8                  matlab.ui.control.Image
        Tab_5                   matlab.ui.container.Tab
        Channel                 matlab.ui.control.DropDown
        Label_99                matlab.ui.control.Label
        CheckBox_2              matlab.ui.control.CheckBox
        rolloff_6               matlab.ui.control.NumericEditField
        Label_77                matlab.ui.control.Label
        coderate                matlab.ui.control.NumericEditField
        EditField_9Label_12     matlab.ui.control.Label
        DropDown_5              matlab.ui.control.DropDown
        Label_75                matlab.ui.control.Label
        MHzEditField_3          matlab.ui.control.NumericEditField
        MHzEditField_3Label     matlab.ui.control.Label
        FFTEditField            matlab.ui.control.NumericEditField
        FFTEditFieldLabel       matlab.ui.control.Label
        CheckBox_11             matlab.ui.control.CheckBox
        Panel_54                matlab.ui.container.Panel
        Lamp                    matlab.ui.control.Lamp
        Switch                  matlab.ui.control.Switch
        Label_117               matlab.ui.control.Label
        DropDown_10             matlab.ui.control.DropDown
        Label_124               matlab.ui.control.Label
        Image7_2                matlab.ui.control.Image
        Tab_10                  matlab.ui.container.Tab
        CheckBox_14             matlab.ui.control.CheckBox
        Label_127               matlab.ui.control.Label
        Channel_2               matlab.ui.control.DropDown
        fsam_rx_6               matlab.ui.control.NumericEditField
        Label_122               matlab.ui.control.Label
        MSaLabel                matlab.ui.control.Label
        fsam_rx_5               matlab.ui.control.NumericEditField
        fsam_rx_4               matlab.ui.control.NumericEditField
        Label_121               matlab.ui.control.Label
        savepath                matlab.ui.control.EditField
        Label_87                matlab.ui.control.Label
        usEditField             matlab.ui.control.NumericEditField
        usEditFieldLabel        matlab.ui.control.Label
        EditField_20            matlab.ui.control.NumericEditField
        Label_120               matlab.ui.control.Label
        DropDown_12             matlab.ui.control.DropDown
        Label_119               matlab.ui.control.Label
        Panel_55                matlab.ui.container.Panel
        Lamp_2                  matlab.ui.control.Lamp
        Switch_2                matlab.ui.control.Switch
        Label_118               matlab.ui.control.Label
        DropDown_11             matlab.ui.control.DropDown
        Label_123               matlab.ui.control.Label
        Image7_3                matlab.ui.control.Image
        Tab_12                  matlab.ui.container.Tab
        Button_42               matlab.ui.control.Button
        Panel_41                matlab.ui.container.Panel
        Button_36               matlab.ui.control.Button
        Panel_40                matlab.ui.container.Panel
        logarea                 matlab.ui.control.TextArea
        Panel_39                matlab.ui.container.Panel
        Attenuation             matlab.ui.control.DropDown
        Label_98                matlab.ui.control.Label
        dBLabel_7               matlab.ui.control.Label
        Button_30               matlab.ui.control.Button
        Panel_38                matlab.ui.container.Panel
        closedevice             matlab.ui.control.Button
        opendevice              matlab.ui.control.Button
        Tab_13                  matlab.ui.container.Tab
        Panel_42                matlab.ui.container.Panel
        TextArea                matlab.ui.control.TextArea
        Tab_14                  matlab.ui.container.Tab
        Panel_57                matlab.ui.container.Panel
        Lamp_7                  matlab.ui.control.Lamp
        EditField_26            matlab.ui.control.NumericEditField
        EditField_26Label       matlab.ui.control.Label
        EditField_25            matlab.ui.control.NumericEditField
        EditField_25Label       matlab.ui.control.Label
        Button_44               matlab.ui.control.Button
        IPEditField_3           matlab.ui.control.EditField
        IPEditField_3Label      matlab.ui.control.Label
        binEditField_2          matlab.ui.control.EditField
        binEditField_2Label     matlab.ui.control.Label
        EditField_24            matlab.ui.control.EditField
        EditField_24Label       matlab.ui.control.Label
        Button_43               matlab.ui.control.Button
        Panel_44                matlab.ui.container.Panel
        Lamp_4                  matlab.ui.control.Lamp
        EditField_18            matlab.ui.control.NumericEditField
        EditField_18Label       matlab.ui.control.Label
        EditField_16            matlab.ui.control.NumericEditField
        Label_106               matlab.ui.control.Label
        Button_38               matlab.ui.control.Button
        IPEditField_2           matlab.ui.control.EditField
        IPLabel                 matlab.ui.control.Label
        binEditField            matlab.ui.control.EditField
        binEditFieldLabel       matlab.ui.control.Label
        EditField_17            matlab.ui.control.EditField
        EditField_17Label       matlab.ui.control.Label
        Button_37               matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

   %添加私有属性 
    properties (Access = private)       
       
        isReceiving = false
        receivingThread    
        %平台platform;
        Platform="";
        %序列号
        SerialNum="";
        %sdr采样率
        MasterClockRate;
        %每帧长度
        samplesPerFrame;
        % 载波频率
          fc=0 ;        
        % 采样率 
       sampleRate;        
        %发射通道
        TXchannel=1;
         %接收通道
        RXchannel=1;
        %发射天线增益(dB)
        TxGain=45;
         %发射天线增益
        RxGain=45;
        %信号频率
        modFreq;
        %起始频率
        StartFreq;
        %终止频率
        StopFreq;
        %频率步进
        StepFreq;
        %是否保存波形文件
        issavewave=false;
         %%设置示波器和频谱仪显示
            spectrumScope = spectrumAnalyzer( ...
                ViewType="spectrum", ...
                SpectrumType="power", ...
                PlotAsTwoSidedSpectrum=true, ...
                Method="welch", ...
                AveragingMethod="exponential", ...
                SpectrumUnits="dBm", ...
                AxesScaling= "auto", ...
                YLimits= [-80 +0], ...   
                ReferenceLoad=50,...            
                ForgettingFactor= 0.95 );
            scope = timescope( ...
                'Name', 'SDR 时域波形', ...
                'TimeSpan', 1e-3, ...              % 显示 1ms 的波形
                'YLimits', [-1  1], ...
                'ShowGrid', true ,...
                'TimeSpanOverrunAction', 'wrap');

         
        IsopenDevice=false % 是否打开衰减器和接收控制芯片
        count=0 ;%日志行数 
        %调用的头文件和dll
        dllPath ="./dll/CH347DLLA64.DLL";
        hdrPath = "./dll/CH347DLL.H";
       
        gCli=[]; % Description
        gCli1=[];
        Property27 % Description

        ALC=false;
    end
    
    methods (Access = private)
         %%AM发射程序
         function out= AMTransmitfunc(app,channel)
            %AM发射程序
           
            % % AM调制参数
            app.modFreq = 5*1e6;         % 调制信号频率 (1 kHz)
           
                FS  = 125e6;
                time   = 0:1/FS:(4096*25-1)/FS;
                modDepth=app.fam_2.Value;
                DC=1/modDepth;

                AMbase = (DC+cos(2*pi*app.modFreq*time));
                AMbase=0.7*AMbase./max(AMbase);
                y    =hilbert(AMbase);
                % plot(x);
                % 量化到 int16（假设满量程 ±1 → ±32767）
                xInt = int16(round(real(y) * 32767));
                yInt = int16(round(imag(y) * 32767));
                zerostream=zeros(1,length(xInt));
                % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
                if channel==1        %通道1有数据
                    ch0_i = xInt(:);   % I 路
                    ch0_q = yInt(:); % Q 路
                    ch1_i = zerostream(:);   % 同上
                    ch1_q =  zerostream(:);
                elseif channel==2               %通道2有数据
                    ch1_i = xInt(:);   % I 路
                    ch1_q = yInt(:); % Q 路
                    ch0_i = zerostream(:);   % 同上
                    ch0_q =  zerostream(:);
                elseif  channel==3
                    ch1_i = xInt(:);   % I 路
                    ch1_q = yInt(:); % Q 路
                    ch0_i = xInt(:);   % I 路
                    ch0_q = yInt(:); % Q 路
                end
                % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
                c=int16(zeros(length(xInt),4));         
                c(:,1)=ch0_i;
                c(:,2)=ch0_q;
                c(:,3)=ch1_i;
                c(:,4)=ch1_q;              
                frameInt16 = reshape(c.',[],1);
                % 若想变成字节流（Little-Endian）
                out = typecast(frameInt16, 'uint8');   % 长度 *2
              
        end

            
        %%FM信号发射程序
        function  out=FMTransmitfunc(app,channel)
            %% 设置参数

            % %每一帧发射信号
            % time=0:1/actualSampleRate:floor(app.samplesPerFrame/InterpolationFactor)/actualSampleRate;%每一帧时间
            FS  = 125e6;
            app.modFreq = app.MHzEditField.Value*1e6;
            time   = 0:1/FS:(4096*8-1)/FS;
            modfactor=app.ffm_3.Value;
            dev=app.modFreq*modfactor;
            msg  = cos(2*pi* app.modFreq *time);     % 测试音频
            fmModulator = comm.FMModulator('SampleRate',  FS, 'FrequencyDeviation', dev);
            txSig = fmModulator(msg.');
            y= txSig / max(abs( txSig));
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y.') * 32767));
            yInt = int16(round(imag(y.') * 32767));
            zerostream=zeros(1,length(xInt));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            if channel==1        %通道1有数据
                ch0_i = xInt(:);   % I 路
                ch0_q = yInt(:); % Q 路
                ch1_i = zerostream(:);   % 同上
                ch1_q =  zerostream(:);
            elseif channel==2               %通道2有数据
                ch1_i = xInt(:);   % I 路
                ch1_q = yInt(:); % Q 路
                ch0_i = zerostream(:);   % 同上
                ch0_q =  zerostream(:);
            elseif  channel==3
                ch1_i = xInt(:);   % I 路
                ch1_q = yInt(:); % Q 路
                ch0_i = xInt(:);   % I 路
                ch0_q = yInt(:); % Q 路
            end
            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
            c=int16(zeros(length(xInt),4));
            c(:,1)=ch0_i;
            c(:,2)=ch0_q;
            c(:,3)=ch1_i;
            c(:,4)=ch1_q;
            frameInt16 = reshape(c.',[],1);
            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2
        end

        %%Pulse信号发送
        function out=PulseTransmitfunc(app,channel)
            if app.PRI_tx.Value*1e-3<app.pulsewidth.Value*1e-6
                ME = MException('MyApp:Custom', '脉冲宽度不能超过脉冲周期');
                throw(ME);
            end


            % app.modFreq = app.fam.Value;         % 调制信号频率 (1 kHz
            FS  = 125e6;
            %pri
            PRI=app.PRI_tx.Value*1e-3*FS;
            N=ceil(PRI/512);  %最少是512的倍数，4路就是4096的倍数
            npoint=512*N;  %time=npoint/FS  秒
            pulseWidth  = round(app.pulsewidth.Value*1e-6*FS);      % 20 µs
            pulsePeriod = round(app.PRI_tx.Value*1e-3*FS);       % 1 ms
            b=round(pulseWidth/pulsePeriod*npoint);  %脉宽点数           
            a=[ones(1,b) zeros(1,npoint-b)];


            y    =hilbert(a);
            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y) * 32767));
            yInt = int16(round(imag(y) * 32767));
            zerostream=zeros(1,length(xInt));
                % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
                if channel==1        %通道1有数据
                    ch0_i = xInt(:);   % I 路
                    ch0_q = yInt(:); % Q 路
                    ch1_i = zerostream(:);   % 同上
                    ch1_q =  zerostream(:);
                elseif channel==2               %通道2有数据
                    ch1_i = xInt(:);   % I 路
                    ch1_q = yInt(:); % Q 路
                    ch0_i = zerostream(:);   % 同上
                    ch0_q =  zerostream(:);
                elseif  channel==3
                    ch1_i = xInt(:);   % I 路
                    ch1_q = yInt(:); % Q 路
                    ch0_i = xInt(:);   % I 路
                    ch0_q = yInt(:); % Q 路
                end
                % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
                c=int16(zeros(length(xInt),4));         
                c(:,1)=ch0_i;
                c(:,2)=ch0_q;
                c(:,3)=ch1_i;
                c(:,4)=ch1_q;              
                frameInt16 = reshape(c.',[],1);
                % 若想变成字节流（Little-Endian）
                out = typecast(frameInt16, 'uint8');   % 长度 *2
        end

             
        %%连续波信号发送
        function  out =CWTransmitfunc(app,channel)
            %% 设置参数

            npoint=4096/8;%生成多少数据点
            %%%%%%%%%%%%%%%%未启用扫频%%%%%%%%%%%%%%%%%%%%%

            AMbase=0.7*ones(1,npoint);
            y    =hilbert(AMbase);
            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y) * 32767));
            yInt = int16(round(imag(y) * 32767));
            zerostream=zeros(1,length(xInt));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            if channel==1        %通道1有数据
                ch0_i = xInt(:);   % I 路
                ch0_q = yInt(:); % Q 路
                ch1_i = zerostream(:);   % 同上
                ch1_q =  zerostream(:);
            elseif channel==2               %通道2有数据
                ch1_i = xInt(:);   % I 路
                ch1_q = yInt(:); % Q 路
                ch0_i = zerostream(:);   % 同上
                ch0_q =  zerostream(:);
            elseif  channel==3
                ch1_i = xInt(:);   % I 路
                ch1_q = yInt(:); % Q 路
                ch0_i = xInt(:);   % I 路
                ch0_q = yInt(:); % Q 路
            end

            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
            c=int16(zeros(length(xInt),4));

            for i=1:length(xInt)
                a=[ch0_i(i),ch0_q(i),ch1_i(i),ch1_q(i)];
                c(i,:)=a;
            end

            frameInt16 = reshape(c.',[],1);
            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2

        end

        %%%QPSK信号发射
        function out=QPSKTransmit(app,channel)
            %% 设置参数
            npoint=4096*1;  %送给FPGA的数据个数
            FS=125e6;
            Rb=app.rb.Value*1e6;
            sps=floor(FS/Rb); %
            M=4;
            %生成的符号数目
            numsymble=floor(npoint/sps);
            numbits=numsymble*log2(M);
            s = RandStream('dsfmt19937');
            % Use the stream to generate five random numbers.

            dataIn=randi(s,[0 1],1,numbits);

            %% 2. 成型滤波（根升余弦滤波器）
            Rolloff= app.rolloff.Value;
            filterspan=10;
            rctFilt = comm.RaisedCosineTransmitFilter(...
                'Shape', 'Square root', ...
                'RolloffFactor',Rolloff, ...
                'FilterSpanInSymbols',filterspan, ...
                'OutputSamplesPerSymbol', sps);
            % 创建星座图对象
            cd = comm.ConstellationDiagram('ShowReferenceConstellation', 0, ...
                'Name', 'QPSK发射星座图', ...
                'Position', [100, 100, 600, 500]);
            % numFrames = ceil(T * 1e6/ app.samplesPerFrame);
            % 创建进度条
            dataIn=reshape(dataIn,[],1);
            modSymbols = pskmod(dataIn,M,pi/4,"gray","InputType","bit");
            % 应用成型滤波
            txBaseband = rctFilt(modSymbols);
            delay=sps*filterspan/2;
            txBaseband =  txBaseband (delay/2+1:end-delay/2);
            y= 0.7*txBaseband ./max(abs( txBaseband ));
            l1=floor(length(y)/4096);
            l2=length(y)/4096;
            if l1~=l2
                l3=4096*(l1+1)-length(y);
                y=[y ;zeros(l3,1)];
            else
                y= y;
            end

            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y) * 32767));
            yInt = int16(round(imag(y) * 32767));
            zerostream=zeros(1,length(xInt));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            if channel==1        %通道1有数据
                ch0_i = xInt(:);   % I 路
                ch0_q = yInt(:); % Q 路
                ch1_i = zerostream(:);   % 同上
                ch1_q =  zerostream(:);
            elseif channel==2               %通道2有数据
                ch1_i = xInt(:);   % I 路
                ch1_q = yInt(:); % Q 路
                ch0_i = zerostream(:);   % 同上
                ch0_q =  zerostream(:);
            elseif  channel==3
                ch1_i = xInt(:);   % I 路
                ch1_q = yInt(:); % Q 路
                ch0_i = xInt(:);   % I 路
                ch0_q = yInt(:); % Q 路
            end
            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
            c=int16(zeros(length(xInt),4));
            c(:,1)=ch0_i;
            c(:,2)=ch0_q;
            c(:,3)=ch1_i;
            c(:,4)=ch1_q;
            frameInt16 = reshape(c.',[],1);
            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2
        end


        %%发射16QAM信号******************************************************
        function out= QAMTransmit_16(app,channel)
            %% 设置参数
            npoint=4096*8;  %送给FPGA的数据个数
            FS=125e6;
            Rb=app.rb.Value*1e6;
            sps=floor(FS/ Rb); %
            M=16;
            %生成的符号数目
            numsymble=floor(npoint/sps);
            numbits=numsymble*log2(M);
            s = RandStream('dsfmt19937');
            % Use the stream to generate five random numbers.

            dataIn=randi(s,[0 1],1,numbits);

            %% 2. 成型滤波（根升余弦滤波器）
            Rolloff=app.rolloff.Value;
            rctFilt = comm.RaisedCosineTransmitFilter(...
                'Shape', 'Square root', ...
                'RolloffFactor', Rolloff, ...
                'FilterSpanInSymbols', 10, ...
                'OutputSamplesPerSymbol', sps);
            % 创建星座图对象

            %% 生成16QAM调制信号

            dataIn=reshape(dataIn,[],1);
            modSymbols = qammod(dataIn,M,"gray","InputType","bit");
            % cd(modSymbols);
            % 应用成型滤波
            txBaseband = rctFilt(modSymbols);
            y= txBaseband ./max(abs( txBaseband ));

            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y) * 32767));
            yInt = int16(round(imag(y) * 32767));
            zerostream=zeros(1,length(xInt));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            if channel==1        %通道1有数据
                ch0_i = xInt(:);   % I 路
                ch0_q = yInt(:); % Q 路
                ch1_i = zerostream(:);   % 同上
                ch1_q =  zerostream(:);
            elseif channel==2               %通道2有数据
                ch1_i = xInt(:);   % I 路
                ch1_q = yInt(:); % Q 路
                ch0_i = zerostream(:);   % 同上
                ch0_q =  zerostream(:);
            elseif  channel==3
                ch1_i = xInt(:);   % I 路
                ch1_q = yInt(:); % Q 路
                ch0_i = xInt(:);   % I 路
                ch0_q = yInt(:); % Q 路
            end
            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
            c=int16(zeros(length(xInt),4));
            c(:,1)=ch0_i;
            c(:,2)=ch0_q;
            c(:,3)=ch1_i;
            c(:,4)=ch1_q;
            frameInt16 = reshape(c.',[],1);
            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2
        end


        %%发射2FSK信号*******************************************************
        function FSK_2Transmit(app)
            %% 设置参数
            app.fc = app.Fc.Value*1e6;         % 载波频率
            app.sampleRate =app.fs.Value*1e6;          % 采样率 (10 MHz)
            app.TxGain  = app.txgain.Value;  % Set USRP radio gain
            T = app.TxDuration_2.Value;             % 发射时长 (秒)
            app.TXchannel=app.DropDown_2.ValueIndex;
             Rolloff=app.rolloff2.Value;      %滚降系数
            Rb =app.rb.Value*1e6;           %码元速率
            sps=ceil(app.sampleRate/Rb);
            app.sampleRate=sps*Rb;         % 更新采样率


             % 设置SDR的采样率

            app.MasterClockRate = app.masterclock_2.Value*1e6/4;

            % 升采样因子
            InterpolationFactor = round(app.MasterClockRate /app.sampleRate);
            DecimationFactor=round(app.MasterClockRate /app.sampleRate);
            actualSampleRate=app.MasterClockRate/DecimationFactor;%实际采样率
            app.samplesPerFrame=3e4; % 每帧采样点数
            app.spectrumScope.SampleRate= actualSampleRate;
            app.MasterClockRate= app.sampleRate*DecimationFactor;

            if(app.Platform==""||app.SerialNum=="")
                % 创建一个带有自定义按钮的对话框
                uialert(app.UIFigure, '未检测到SDR设备！请检查连接。','设备错误');
                return;
            end
             %% 设置USRP发射器和接收器
            try
                tx = comm.SDRuTransmitter( 'Platform', app.Platform, ...
                    'SerialNum', app.SerialNum, ... % 使用检测到的设备
                    'CenterFrequency', app.fc, ...
                    'Gain',70.5-app.TxGain , ...
                    "ChannelMapping",app.TXchannel,...
                    'InterpolationFactor', InterpolationFactor, ... % 主机采样率调整
                    'MasterClockRate', app.MasterClockRate);    % B210主时钟
            catch ME
                % 创建一个带有自定义按钮的对话框
                uialert(app.UIFigure, ME.message,'设备错误');
                return;
            end
            
            %计算需要生成多少比特
            M=2;
            time=app.samplesPerFrame/(app.sampleRate*DecimationFactor);
            numberbits=Rb*time*log2(M);
            
            %% 2. 成型滤波（根升余弦滤波器）
            rctFilt = comm.RaisedCosineTransmitFilter(...
                'Shape', 'Square root', ...
                'RolloffFactor', Rolloff, ...
                'FilterSpanInSymbols', 10, ...
                'OutputSamplesPerSymbol', sps);
            % 创建星座图对象
            % cd = comm.ConstellationDiagram('ShowReferenceConstellation', 0, ...
            %     'Name', '16QAM发射星座图', ...
            %     'Position', [100, 100, 600, 500], ...
            %     "XLimits",[-3 3] ,...
            %     "YLimits",[-4 4] ); 
             numFrames = ceil(T * 1e6/ app.samplesPerFrame);
             % 创建进度条
              %强制弹出频谱窗口
            %  reset(app.spectrumScope);
            % show(app.spectrumScope);
            freqsep=8e5;%频率间隔
            nsamp=sps;%每个符号输出的点数（每个符号采多少个点）
            
            app.Button_starttransmit_2.Text="发送中...";
            %% 生成FSK调制信号
            dataIn = randi([0 M-1], numberbits, 1); % 2FSK每个符号2比特
            % QPSK调制
            % Y = pskmod(X,M,phaseoffset,symorder,InputType='bit')
            txBaseband=fskmod(dataIn,M,freqsep,nsamp,app.sampleRate,"cont","gray");
            txBaseband=rctFilt(txBaseband);
             app.spectrumScope(txBaseband);
            txFrame= txBaseband/max( abs(txBaseband));
             app.spectrumScope(txFrame);
            for frame = 1:numFrames
               
                 tx(txFrame);   %发射信号  app.spectrumScope(txFrame);
                 % cd(modSymbols);
                 % 更新进度条
                 % progress = frame / numFrames;
                 % waitbar(progress, h, sprintf('发射进度: %d%%', round(progress * 100)));

            end
            % 关闭进度条
            app.Button_starttransmit_2.Text="数字射频信号产生";
            release(tx);
           
        end
        %%发射全0信号表示仪器停止
        function out =ZerosTransmit(app)
            %% 设置参数
            npoint=4096/8;%生成多少数据点         
            AMbase=0.7*zeros(1,npoint);
            y    =hilbert(AMbase);
            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y) * 32767));
            yInt = int16(round(imag(y) * 32767));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            ch0_i = xInt(:);   % I 路
            ch0_q = yInt(:); % Q 路
            ch1_i = xInt(:);   % 同上
            ch1_q =  yInt(:);

            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
            c=int16(zeros(length(xInt),4));

            for i=1:length(xInt)
                a=[ch0_i(i),ch0_q(i),ch1_i(i),ch1_q(i)];
                c(i,:)=a;
            end

            frameInt16 = reshape(c.',[],1);
       
            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2
        end


         %低通滤波器   
        function results = Lowpassfilter(app,Fs,Fc,signal)
           
            N    = 120;       % Order
            % Fc   = 10800;    % Cutoff Frequency
            flag = 'scale';  % Sampling Flag

            % Create the window vector for the design algorithm.
            win = hamming(N+1);

            % Calculate the coefficients using the FIR1 function.
            b  = fir1(N, Fc/(Fs/2), 'low', win, flag);
            results=filter(b,1,signal);
        end
        %%%开关滤波
        function [] = RadioFreqfilter(app,buttonselectname)

          
             %设置接收通道
             SpiI2cGpioDevIndex = uint32(0);    % ulong 对应 uint32
            oEnable = 255;              % uint8  管脚使能
            oSetDirOut = 255;            % uint8  输入/输出控制 1：输出
            oSetDataOut = 255;           % uint8  输出的数据
            % 先清掉旧库
            if libisloaded('CH347DLLA64')
                unloadlibrary CH347DLLA64;
            end
            %加载动态库
            var1=app.dllPath;
            var2=app.hdrPath;
            [notfound, warnings] =loadlibrary(app.dllPath, app.hdrPath, 'alias', 'mm');
            if ~libisloaded('mm')
                uialert(app.UIFigure,"Dll未加载","提示","Icon","info");
                return;
            else
                cc= libfunctions('mm', '-full'); % 显示所有导出函数
            end
            if ~isempty(notfound)
                error(char(notfound));
            end

            hDevPtr   = calllib('mm', 'CH347OpenDevice',0);%打开设备
            app.IsopenDevice=true;
            if app.IsopenDevice==false
                uialert(app.UIFigure,"请先打开设备","提示","Icon","info");
                return;
            end
            try
               
               
                channel=app.ButtonGroup.SelectedObject.Text; %通道
                bitArray=[1 0 0 0 0 0 0 0];
                switch channel  %设置衰减值
                    case "低通"
                        bitArray(5)=1;
                        oSetDataOut=bi2de(bitArray,"left-msb");
                        if   calllib('mm', 'CH347GPIO_Set', ...
                                SpiI2cGpioDevIndex, ...
                                oEnable, ...
                                oSetDirOut, ...
                                oSetDataOut)
                           
                            app.logMessage("设置通道"+  channel+"成功!"); %输出日志信息
                        end
                    case "带通1"
                        bitArray(8)=1;
                        bitArray(6)=1;
                        oSetDataOut=bi2de(bitArray,"left-msb");
                        if   calllib('mm', 'CH347GPIO_Set', ...
                                SpiI2cGpioDevIndex, ...
                                oEnable, ...
                                oSetDirOut, ...
                                oSetDataOut)
                             app.logMessage("设置通道"+  channel+"成功!"); %输出日志信息
                        end
                 case "带通2"
                     bitArray(7)=1;
                     
                     oSetDataOut=bi2de(bitArray,"left-msb");
                     if   calllib('mm', 'CH347GPIO_Set', ...
                             SpiI2cGpioDevIndex, ...
                             oEnable, ...
                             oSetDirOut, ...
                             oSetDataOut)
                         app.logMessage("设置通道"+  channel+"成功!"); %输出日志信息
                     end
                       
                       
                end
                %关闭设备
                devIndex  = uint32(0);          % 第 0 号设备
                Isclosed=calllib("mm","CH347CloseDevice",devIndex);
                app.IsopenDevice=false;
                %释放掉库
                unloadlibrary mm;
            catch  ME  %捕获异常
                app.logMessage(sprintf("错误: %s", ME.message));
            end


        end
        
        
        %% 2. 回调：客户端连上/断开时更新全局变量
        function saveClient(app,src,event,switchname,lamp)
            try
                app.gCli =src;
                if src.Connected==true
                    switchname.Value="开";
                    lamp.Color=[0.00,1.00,0.00];%绿色
                    % app.Lamp_8.Color=[0.00,1.00,0.00];%绿色
                    % app.TextArea_2.Value="已连接";
                else
                  switchname.Value="关";
                    lamp.Color=[0.80,0.80,0.80];%灰色
                     delete(app.gCli);
                     delete(app.gCli1);
                end
            catch ME
                uialert(app.UIFigure,ME.message,"错误:");
            end
        end
         %%数据接收连接
       function saveClient1(app,src,event,switchname,lamp)
            try
                app.gCli =src;
                if src.Connected==true
                    switchname.Value="开";
                    lamp.Color=[0.00,1.00,0.00];%绿色
                    % app.Lamp_8.Color=[0.00,1.00,0.00];%绿色
                    % app.TextArea_2.Value="已连接";
                else
                  switchname.Value="关";
                    lamp.Color=[0.80,0.80,0.80];%灰色
                    delete(app.gCli);
                     delete(app.gCli1);
                end
            catch ME
                uialert(app.UIFigure,ME.message,"错误:");
            end
        end


        function wave=wavegenerate(app)
            % 1. 读取二进制文件
            filename = "C:\Users\admin\Desktop\共享文件夹\sdr_iq_data.bin";
            fid = fopen(filename, 'rb');
            if fid == -1
                error('无法打开文件: %s', filename);
            end

            % 读取所有字节数据
            fileBytes = fread(fid, inf, 'uint8=>uint8');
            fclose(fid);

            disp(['文件总字节数: ', num2str(length(fileBytes))]);
            % 将字节流转换回int16数组（Little-Endian）
            % 注意：需要确保字节顺序与写入时一致
            data_flat = typecast(fileBytes, 'uint8');

            % 重塑为90行8列的矩阵（按行优先顺序）
            % data_reconstructed = reshape(data_flat, 8,[])'; % 先reshape为8列90行，再转置
            % rec_array=data_reconstructed;
            wave=data_flat;
        end


      %**********************************数据打包函数************

      function cmd_struct = pack_cmd(~,cmd_type, varargin)
          % 创建命令结构体
          % cmd_type: 命令类型 (1-18, 100)
          % varargin: 根据命令类型传入的参数

          % 基础结构体模板
          cmd_struct = struct();
          % 公共头部字段（默认值）
          cmd_struct.head_0 = uint8(0x55);      % 同步头0
          cmd_struct.head_1 = uint8(0xAA);      % 同步头1
          cmd_struct.match_id = uint8(0x01);       % 匹配ID
          cmd_struct.cmd = uint8(cmd_type);     % 命令类型
          cmd_struct.frame_len_reserved =typecast(uint16(0),'uint8') ;     % 帧长度（根据数据填充）
          cmd_struct.cmd_stream_id =typecast(uint16(0),'uint8') ; % 命令流ID
          %4个字节用来传长度
          cmd_struct.frame_len_l =typecast(uint16(0),'uint8') ; % 命令流ID
          cmd_struct.frame_len_h =typecast(uint16(0),'uint8') ; % 命令流ID


          % 根据命令类型处理不同的数据字段
          switch cmd_type
              case 1  % CMD_GET_VERSION
                  cmd_struct = setVersionFields(cmd_struct, varargin{:});
              case {2, 3, 4}  % SPI写命令
                  cmd_struct = setSpiWriteFields(cmd_struct, cmd_type, varargin{:});
              case {5, 6, 7}  % SPI读命令
                  cmd_struct = setSpiReadFields(cmd_struct, cmd_type, varargin{:});
              case 8  % CMD_WRITE_WAVE_BRAM    %波形发送
                  cmd_struct = setWaveBramFields(cmd_struct, varargin{:});
                  cmd_struct =help(cmd_struct);
              case 9  % CMD_WRITE_AXI_REG
                  cmd_struct = setAxiWriteFields(cmd_struct, varargin{:});
              case 10 % CMD_READ_AXI_REG
                  cmd_struct = setAxiReadFields(cmd_struct, varargin{:});
              case 11 % CMD_SET_TX_NCO    频率设置
                  cmd_struct = setTxNcoFields(cmd_struct, varargin{:});
                  cmd_struct =help(cmd_struct);
              case 12 % CMD_SET_RX_NCO
                  cmd_struct = setRxNcoFields(cmd_struct, varargin{:});
              case 13 % CMD_LMH6401_WRITE
                  cmd_struct = setLmh6401Fields(cmd_struct, 'write', varargin{:});
              case 14 % CMD_LMH6401_READ
                  cmd_struct = setLmh6401Fields(cmd_struct, 'read', varargin{:});
              case 15 % CMD_SET_TX_GAIN   设置发射增益
                  cmd_struct = setGainFields(cmd_struct,  varargin{:});
                  cmd_struct =help(cmd_struct);
              case 16 % CMD_SET_RX_GAIN   设置接收增益
                  cmd_struct = setGainFields(cmd_struct,  varargin{:});
              case 17 % CMD_ADC_RETDATA
                  cmd_struct = setAdcRetDataFields(cmd_struct, varargin{:});
              case 18 % CMD_SET_DAC_TXEN
                  cmd_struct = setDacTxEnFields(cmd_struct, varargin{:});
              case 100 % CMD_RET
                  cmd_struct = setRetFields(cmd_struct, varargin{:});
              otherwise
                  warning('未知命令类型: %d', cmd_type);
          end
          %%辅助函数
          function cmd = setSpiWriteFields(cmd, cmd_type, spi_addr, spi_data)
              % 设置SPI写命令字段
              cmd.cmd = uint8(cmd_type);
              cmd.spi_addr = uint16(spi_addr);
              cmd.spi_data = uint16(spi_data);
              cmd.frame_len = uint16(4);  % 2字节地址 + 2字节数据
          end

          %设置频率
          function cmd = setTxNcoFields(cmd, frequency, page)
              % tx_freq  = uint32(varargin{1});
              %                   page     = uint8(varargin{2});
              %                   pld(1:4) = typecast(tx_freq,'uint8');
              %                   pld(5)   = page;
              % 设置TX NCO字段
              cmd.tx_fre = typecast(uint32(frequency),'uint8');
              cmd.tx_nco_page = uint8(page);
              % cmd.frame_len = typecast(uint16(5),'uint8');  % 4字节频率 + 1字节页
          end

          function cmd = setRxNcoFields(cmd, frequency, page)
              % 设置RX NCO字段
              cmd.rx_fre = uint32(frequency);
              cmd.rx_nco_page = uint8(page);
              cmd.frame_len = uint16(5);
          end

          function cmd = setGainFields(cmd,  channel, gain)
              % 设置增益字段
              % tx_rx: 'tx' 或 'rx'
              % gain: int8类型，范围-128~127
              cmd.channal = typecast(uint16(channel),'uint8');
              cmd.gain = uint8(gain);
          end

          function cmd = setDacTxEnFields(cmd, tx_en_page, tx_ch0_en, tx_ch1_en)
              % 设置DAC TX使能字段（包含位字段）
              if nargin == 2
                  % 直接传入tx_en_page值
                  cmd.tx_en_page = uint8(tx_en_page);
              else
                  % 通过位字段设置
                  cmd.tx_en_page = uint8(0);
                  if tx_ch0_en
                      cmd.tx_en_page = bitset(cmd.tx_en_page, 1, 1);  % 位0
                  end
                  if tx_ch1_en
                      cmd.tx_en_page = bitset(cmd.tx_en_page, 2, 1);  % 位1
                  end
              end
              cmd.frame_len = uint16(1);
          end


          %波形数据
          function cmd = setWaveBramFields(cmd, base_addr, total_frame, cur_frame, wavedata)
              % 设置波形BRAM字段
              %输入：base_addr：偏移量
              %      total_frame：总帧数量
              %      cur_frame:当前帧是第几帧
              %      wavedata:波形数据
              % 输出：结构体
              cmd.base_addr_offset = typecast(uint32(base_addr),'uint8') ;    %偏移
              cmd.total_frame = typecast(uint16(total_frame),'uint8');       %总帧数
              cmd.cur_frame = typecast(uint16(cur_frame),'uint8');           %当前帧
              cmd.wavedata = uint8(wavedata);
          end

          function cmd = setRetFields(cmd, rec_cmd, task_frame_data, ret_status)
              % 设置返回命令字段
              cmd.rec_cmd = uint16(rec_cmd);
              cmd.task_frame_data = uint16(task_frame_data);
              cmd.ret_task_status = int32(ret_status);
              cmd.frame_len = uint16(2 + 2 + 4);  % 2+2+4=8字节
          end


          %*************************
          function out =help(input)
              %输入input：结构体
              %输出out：列向量(结构体成员的值串联起来)
              fn = fieldnames(input);
              b=[];
              for i=1:length(fn)
                  temp=fn{i};
                  b=[b; reshape(input.(temp),[],1)];
              end
              if length(b)<32
                  out=zeros(1,32);
                  out(1:length(b))=b;
              else
                  out=b;
              end
          end
      end
%********************************************************************


      %********************************************
        %连接设备
        function connectflag=connectfunc(app,time,flag)
           starttime=  tic;   %开始计时
            while true   
                if flag
                    if ~isempty(app.gCli) && app.gCli.Connected==1 && ~isempty(app.gCli1) && app.gCli1.Connected==1
                        connectflag=true;
                      a=app.gCli;
                      b=app.gCli1;

                        return;
                    else
                        if toc(starttime)<time  %超过time 秒就退出程序
                            pause(0.01);     % 没连上就空转
                        else
                            uialert(app.UIFigure,"设备连接超时","提示");                          
                            connectflag=false;
                            return;
                        end
                    end
                else
                    if ~isempty(app.gCli) && app.gCli.Connected==1
                        connectflag=true;
                        a=app.gCli;                         
                        b=app.gCli1;
                        return;
                    else
                        if toc(starttime)<time  %超过time 秒就退出程序
                            pause(0.01);     % 没连上就空转
                        else
                            uialert(app.UIFigure,"设备连接超时","提示");                           
                            connectflag=false;
                            return;
                        end
                    end
                end
                pause(0.1);
            end
        end
        %衰减设置
        function attenuationflag= attenuation()
            attenuationflag=flase;
            pasetime=0.6;
            try
                %设置增益
                gainframe=pack_cmd(app,15, 1,3);%第三个参数为1:通道1，2：通道2，3:通道1和2
                pause(pause);
                write(app.gCli,gainframe);

            catch ME
                uialert(app.UIFigure,ME.message,"提示");
            end
        end
        %板子衰减
        function opendevice1(app, event)
              %打开控制芯片
            % 先清掉旧库
             try
            if libisloaded('CH347DLLA64')
                unloadlibrary CH347DLLA64;
            end

           
            % "C:\Users\admin\Desktop\电科SDR\衰减器控制\CH347DLL.H"
            var1=app.dllPath;
            var2=app.hdrPath;
            [notfound, warnings] =loadlibrary(app.dllPath, app.hdrPath, 'alias', 'mm');
            if ~isempty(notfound)
                error(char(notfound));
            end       
            % 显式指定参数类型（关键！）
            % SpiI2cGpioDevIndex = uint32(0);    % ulong 对应 uint32
            % oEnable = 255;              % uint8
            % oSetDirOut = 255;            % uint8
            % oSetDataOut = 0;           % uint8
          
            % if ~libisloaded('mm')
            %     error('DLL 未加载！');
            % else
            %     libfunctions('mm', '-full'); % 显示所有导出函数
            % end

            % 2. 调用 CH347OpenDevice
            devIndex  = uint32(0);          % 第 0 号设备
            hDevPtr   = calllib('mm', 'CH347OpenDevice', devIndex);
            app.logMessage("设备打开成功!");
            app.IsopenDevice=true;
            app.closedevice.Enable="on";
            app.opendevice.Enable="off";
            % %
            % RetVal = calllib('mm', 'CH347GPIO_Set', ...
            %     SpiI2cGpioDevIndex, ...
            %     oEnable, ...
            %     oSetDirOut, ...
            %     oSetDataOut);
            % %关闭设备
            % % Isclosed=calllib("mm","CH347CloseDevice",devIndex);
            % 
            % % 3. 检查结果
            % if RetVal == 1
            %     disp('GPIO 设置成功');
            % else
            %     disp('GPIO 设置失败');
            % end
            % % 4. （可选）释放库
            unloadlibrary mm;            
            catch  ME
                app.logMessage(sprintf("错误:设备打开失败 %s", ME.message));
            end 
        end

        %断开设备
        function disconnectflag=disconnectfunc(app,time)
            starttime=  tic;   %开始计时
            disconnectflag=false;
            ans=[];
            delete(app.gCli);
            app.gCli=[];
            b= app.gCli;            
            pause(0.1);
            disconnectflag=true;
        end


        function source = sourcechoose(app)
            if   app.CheckBox_8.Value==true
                source="AM";
                return;
            end
            if   app.CheckBox_9.Value==true
                source="FM";
                return;
            end
            if   app.CheckBox_10.Value==true
                source="Pulse";
                return;
            end
            if app.CheckBox_8.Value==false&&app.CheckBox_9.Value==false&&...
                    app.CheckBox_10.Value==false

                source="CW";
            end

        end
        %转换接收的字节数据为double
        function results = func30(app,fileBytes,channel)
            data_flat = typecast(fileBytes(9:end), 'int16');

            % 重塑为90行8列的矩阵（按行优先顺序）
            data_reconstructed = reshape(data_flat, 8,[])'; % 先reshape为8列90行，再转置
            rec_array=data_reconstructed;
            b=size(rec_array);
            a=[];
            if channel==1
                for i=1:b(1)
                    c= flip(rec_array(i,1:4)) ;
                    a=[a c];
                end
            else
                for i=1:b(1)
                    c= flip(rec_array(i,5:8)) ;
                    a=[a c];
                end
            end
            a=double(a)./32767;
            results=a;

        end

        
        function result = opdefunc34(app,number)
            %  dllPath ="./dll/CH347DLLA64.DLL";
            % % dllPath ="C:\Users\admin\Desktop\电科SDR\衰减器控制\dll\CH347DLLA64.DLL"
            % hdrPath = "./dll/CH347DLL.H";
            % % 检查文件是否存在
            % if exist(hdrPath, 'file')
            %     disp('文件存在');
            %     % 这里可以添加您的处理代码
            % else
            %     warning('文件不存在: %s', hdrPath);
            % 
            %     % 显示当前工作目录以便调试
            %     disp(['当前工作目录: ' pwd]);
            % 
            %     % 列出当前目录内容
            %     dir
            % end
            % % hdrPath ="C:\Users\admin\Desktop\电科SDR\衰减器控制\dll\CH347DLL.H";
            % % 先清掉旧库
            % % if libisloaded('CH347DLLA64')
            %     unloadlibrary CH347DLLA64;
            % end

            [notfound, warnings] =loadlibrary(app.dllPath, app.hdrPath, 'alias', 'mm');
            if ~isempty(notfound)
                error(char(notfound));
            end           
            % 显式指定参数类型（关键！）
            SpiI2cGpioDevIndex = uint32(0);    % ulong 对应 uint32
            oEnable = 255;              % uint8
            oSetDirOut = 255;            % uint8
            oSetDataOut =number;         % uint8

            if ~libisloaded('mm')
                % error('DLL 未加载！');
                uialert(app.UIFigure,"未成功加载衰减器的dll文件","提示");
                return;
            % else
            %     libfunctions('mm', '-full'); % 显示所有导出函数
            end


            % 2. 调用 CH347OpenDevice
            devIndex  = uint32(0);          % 第 0 号设备
            hDevPtr   = calllib('mm', 'CH347OpenDevice', devIndex);%打开device
             RetVal0 = calllib('mm', 'CH347GPIO_Set', ...
                SpiI2cGpioDevIndex, ...
                oEnable, ...
                oSetDirOut, ...
                255);
             pause(0.1);
            RetVal = calllib('mm', 'CH347GPIO_Set', ...
                SpiI2cGpioDevIndex, ...
                oEnable, ...
                oSetDirOut, ...
                oSetDataOut);
            %
            % Isclosed=calllib("mm","CH347CloseDevice",devIndex);

            %% 3. 检查结果
            % if RetVal == 1
            %     disp('GPIO 设置成功');
            % else
            %     disp('GPIO 设置失败');
            % end

            %% 4. （可选）释放库
            unloadlibrary mm;
           
       result= RetVal;
        end
        
        
        %选择板子衰减
        function flag= func36(app,attenuation)
            flag=false;
            bitArray=[0 0 0 0 0 0 0 0];
            try
                %衰减的动态范围大约36dB
                attenuationrange=36;
                %转化为128个状态中的1个
                oSetDataOut=round(attenuation/attenuationrange*128);
                %设置衰减
                if   opdefunc34(app,oSetDataOut)

                    flag=true;
                    return;
                end
            catch ME
                uialert(app.UIFigure,ME.message,"提示");
            end
        end
        
        function pow=bytes2double(app,fileBytes)
            data_flat = typecast(fileBytes, 'int16');
            pow=-100;
            % 重塑为90行8列的矩阵（按行优先顺序）
            data_reconstructed = reshape(data_flat, 8,[])'; % 先reshape为8列90行，再转置
            rec_array=data_reconstructed;
            b=size(rec_array);
            a=[];
            for i=1:b(1)
                c= flip(rec_array(i,1:4)) ;
                a=[a c];
            end
            FS=1e9;
            data=double(a)/32767;
            x=data;
            [pxx,f] = periodogram(x,hamming(length(x)),length(x),FS,'power');
                [pwrest,idx] = max(pxx);
                pow=10*log10(pwrest*1000);
            % Y=abs(fftshift(fft(data)))/length(data);
            % a=data;
            % fs=50e6;
            % f=-fs/2:fs/length(a):fs/2-fs/length(a);
            % [psor,lsor] = findpeaks(Y,'SortStr','descend');
            % a1=psor(1);
            % a2=psor(2);
            % if a1==a2
            %     index=find(Y==a2);
            %     pow=Y(index(end));
            %     return;
            % elseif a1>5e4
            % 
            %     index=find(Y==a1);
            %     pow=Y(index);
            %     return;
            % else
            %     index=find(Y==a2);
            %     pow=Y(index);
            %     return;
            % end
        end


        %重新设置衰减
        function  resetattenuation(app,attenuation,channel)
            %设置衰减

            % gainframe=pack_cmd(app,15, app.ALCDropDown.ValueIndex,app.dBEditField.Value);%第三个参数为1:通道1，2：通道2，3:通道1和2;
            % pause(pausetime);
            % write(app.gCli, gainframe);
            % var=str2double(app.Attenuation.Value);
            % if ~func36(app,var)
            %     uialert(app.UIFigure,"大衰减设置失败","提示");
            % end
            pausetime=0.6;
            if attenuation>-5
                gainframe=pack_cmd(app,15, channel,attenuation-5);%第2个参数为1:通道1，2：通道2，3:通道1和2;           
                write(app.gCli, gainframe);
                R=read(app.gCli,length(gainframe));
            end
        end

        %查询第一个数据位置
        function result=select(app,data)
            result=data;
            meanofdata=mean(data);
            newdata=data-meanofdata;
            a=max(newdata)/2;
            b=newdata-a;
            index=-1;
            for i=1:length(b)-1
                if b(i)==0||(b(i)<0&&b(i+1)>0)
                    index=i;
                    break;
                end
            end
            if index==-1
                uialert(app.UIFigure,"查询第一个数据位置失败","警告")

                return;
            end
            result=newdata(index:index+900);
        end


        %设置带通滤波器
        function setpassbandfilter(app,index)
             %设置接收通道
             SpiI2cGpioDevIndex = uint32(0);    % ulong 对应 uint32
            oEnable = 255;              % uint8  管脚使能
            oSetDirOut = 255;            % uint8  输入/输出控制 1：输出
            oSetDataOut = 255;           % uint8  输出的数据
            % if app.IsopenDevice==false
            %     uialert(app.UIFigure,"请先打开设备","提示","Icon","info");
            %     return;
            % end
            try
                % 先清掉旧库
                if libisloaded('CH347DLLA64')
                    unloadlibrary CH347DLLA64;
                end
                %加载动态库
                var1=app.dllPath;
                var2=app.hdrPath;
                [notfound, warnings] =loadlibrary(app.dllPath, app.hdrPath, 'alias', 'mm');
                if ~libisloaded('mm')
                    uialert(app.UIFigure,"Dll未加载","提示","Icon","info");
                    return;
                else
                   cc= libfunctions('mm', '-full'); % 显示所有导出函数
                end
                if ~isempty(notfound)
                    error(char(notfound));
                end
               
                hDevPtr   = calllib('mm', 'CH347OpenDevice',0);%打开设备
                 channel="DC-40MHz";
               if index==1
                   channel="DC-40MHz";
               elseif index==2
                   channel="500-850MHz";
               else
                   channel="1100-1450MHz";

               end
               
                bitArray=[1 0 0 0 0 0 0 0];
                switch channel  %设置衰减值
                    case "DC-40MHz"
                        bitArray(5)=1;
                        oSetDataOut=bi2de(bitArray,"left-msb");
                        if   calllib('mm', 'CH347GPIO_Set', ...
                                SpiI2cGpioDevIndex, ...
                                oEnable, ...
                                oSetDirOut, ...
                                oSetDataOut)
                           
                            app.logMessage("设置通道"+  channel+"成功!"); %输出日志信息
                        end
                    case "500-850MHz"
                        bitArray(8)=1;
                        bitArray(6)=1;
                        oSetDataOut=bi2de(bitArray,"left-msb");
                        if   calllib('mm', 'CH347GPIO_Set', ...
                                SpiI2cGpioDevIndex, ...
                                oEnable, ...
                                oSetDirOut, ...
                                oSetDataOut)
                             app.logMessage("设置通道"+  channel+"成功!"); %输出日志信息
                        end
                 case "1100-1450MHz"
                     bitArray(7)=1;
                     
                     oSetDataOut=bi2de(bitArray,"left-msb");
                     if   calllib('mm', 'CH347GPIO_Set', ...
                             SpiI2cGpioDevIndex, ...
                             oEnable, ...
                             oSetDirOut, ...
                             oSetDataOut)
                         app.logMessage("设置通道"+  channel+"成功!"); %输出日志信息
                     end
                       
                       
                end
                %释放掉库
                unloadlibrary mm;
            catch  ME  %捕获异常
                app.logMessage(sprintf("错误: %s", ME.message));
            end
        end
        
        %**************用于开关使能，指示灯变色
        function Switcher(app,switchname,Lampname,state)
            if state=="开"
                switchname.Enable="on";
                switchname.Value="开";
                Lampname.Color=[0.00 1.00 0.00]; %绿色"
                % app.Lamp_3.Color=[0.00 1.00 0.00]; %绿色"
            end
            if state=="关"
                switchname.Enable="on";
                switchname.Value="关";
                Lampname.Color=[0.80 0.80 0.80];%灰色
                %删除tcpsever
                if ~isempty(app.gCli)
                    delete(app.gCli);
                end
                if ~isempty(app.gCli1)
                    delete(app.gCli1);
                end
            end

        end
       %***************************
       %波形下载失败

       function WaveSendFialed(app,Button_WaveSend,ME)
           if isempty(ME.message)
               uialert(app.UIFigure,"数据下发失败","警告");
           else
               uialert(app.UIFigure,ME.message,"警告");
           end
           Button_WaveSend.Text="波形下载";
           Button_WaveSend.Enable="on";
       end
        %**************波形写入***
        function WaveWrite(app,Waveform,unitpoint,Button_WaveSend)
            % Waveform:波形数据

            % unitpoint=4096*9;  %16k Bytes
            FrameNum=floor(length(Waveform)/unitpoint);
            waveframe=uint8(zeros(FrameNum,unitpoint+20));
            extrawavedata=zeros(length(Waveform)-unitpoint*FrameNum,1);
            extrawavedata=Waveform(end-length(extrawavedata)+1:end);
            % cmd = setWaveBramFields(cmd, base_addr, total_frame, cur_frame, wavedata)
            for i=1:FrameNum
                out1=Waveform((i-1)*unitpoint+1:i*unitpoint);
                frame = pack_cmd(app,8,(i-1)*unitpoint,FrameNum+1,i-1,out1);
                waveframe(i,:)=frame;
            end

            if ~isempty(extrawavedata)
                extraframe=pack_cmd(app,8, FrameNum*unitpoint,FrameNum+1,FrameNum,extrawavedata);
            end
            %
            for i=1:FrameNum
                frame=reshape(waveframe(i,:),[],1);
                write(app.gCli, frame);
                %
                A=read(app.gCli,24,"uint8");               
                A2=typecast(uint8(A(17:20)),'int32'); %状态0：正常  <0 ：错误
                if A2~=0
                    WaveSendFialed(app,Button_WaveSend,ME);
                end
                pause(0.02);
            end

            if ~isempty(extrawavedata)
                write(app.gCli,extraframe);
                A=read(app.gCli,24,"uint8");               
                StateFlag=typecast(uint8(A(17:20)),'int32'); %状态0：正常  <0 ：错误
                if StateFlag~=0
                    WaveSendFialed(app,Button_WaveSend,ME);
                end
            end
        end

%********************设置频率、增益等
function WriteCommand(app,type,frame)
    write(app.gCli, frame);
    A=read(app.gCli,24,"uint8");
    pause(0.05);
    % A1=uint16(A(6));        %下位机收到的字节数
    A2=typecast(uint8(A(17:20)),'int32'); %状态0：正常  <0 ：错误
    if A2<0
        switch  type
            case "频率"
                uialert(app.uifigure,"频率设置失败");
                return;
            case "增益"
                uialert(app.uifigure,"增益设置失败");
                return;
        end
        ME = MException('MyApp:BadInput', "未能识别的类型");
        throw(ME);
    end
end

 %********************仪器连接*****************************************************************
 function InstrumentConnection(app,ip,port,Button_starttransmit,Lamp_3)
     svr=app.gCli;
     svr1=app.gCli1;
     switch port
         case 8931
             if   isempty(svr)||~isvalid( svr)
                 delete(app.gCli);
                 pause(0.1);
                 app.gCli= tcpserver(ip,port, ...
                     "ConnectionChangedFcn", @(src,event)app.saveClient(src,event,Button_starttransmit,Lamp_3), ...
                     "ByteOrder","little-endian","Timeout",3);
                 return;
             elseif svr.Connected==false
                 delete(app.gCli);
                 pause(0.1);
                 app.gCli= tcpserver(ip,port, ...
                     "ConnectionChangedFcn", @(src,event)app.saveClient(src,event,Button_starttransmit,Lamp_3), ...
                     "ByteOrder","little-endian","Timeout",3);
                 return;
             end

         case 8932
             if   isempty(svr1)||~isvalid( svr1)
                 delete(app.gCli1);
                 pause(0.1);
                 app.gCli1= tcpserver(ip,port, ...
                     "ConnectionChangedFcn", @(src,event)app.saveClient1(src,event,Button_starttransmit,Lamp_3), ...
                     "ByteOrder","little-endian","Timeout",3);
                 return;
             elseif svr1.Connected==false
                 delete(app.gCli1);
                 pause(0.1);
                 app.gCli1= tcpserver(ip,port, ...
                     "ConnectionChangedFcn", @(src,event)app.saveClient(src,event,Button_starttransmit,Lamp_3), ...
                     "ByteOrder","little-endian","Timeout",3);
                 return;
             end
         otherwise
             uialert(uifigure,"端口号错误","提示");
             return;
     end
 end

%************************************************************************************************************
%********************断开仪器*******************************************************************************       
function InstrumentDisConnection(app,port)
    svr=app.gCli;
    svr1=app.gCli1;
    try
        switch port
            case 8931
                if isvalid(svr)&&svr.Connected==true
                    out = ZerosTransmit(app);
                    frame=pack_cmd(app,8, 0,1,0,out);
                    write( app.gCli, frame);
                    % A=read(svr,10,"uint16");
                    % A2=typecast(uint16(A(7:8)),'int32'); %状态0：正常  <0 ：错误
                    % if A2~=0
                    %     uialert(uifigure,"仪器关闭失败","提示");
                    % end
                end
                delete(svr);
                pause(0.5);
            case 8932
                delete(svr1);
                pause(0.5);
            otherwise
                uialert(uifigure,"端口号错误","提示");
                return;
        end
    catch ME
        delete(svr);
        delete(svr1);
        uialert(uifigure,"断开仪器出现问题:代码位置1825行","提示");
    end
end
%************************************************************************************************************


    end


    methods (Access = public)
        %%打开频谱仪
        function openspectrum(app)
            try
                if app.CheckBox_11.Value==false&&app.CheckBox_2.Value==false
                    uialert(app.UIFigure,"请选择至少一个复选框","提示");
                    app.Lamp.Color=[0.90 0.90 0.90]; %指示灯变回灰色
                    app.Switch.Value="关";
                    return;
                end
                receivechannel=app.DropDown_10.ValueIndex;
                %建立连接
                [~,hostname] = system('hostname');
                hostname = string(strtrim(hostname));
                ip = resolvehost(hostname,"address");
                %建立TCP连接
                %% 1. 启动服务器
                % ip   = '0.0.0.0';     % 监听所有本地地址
                port = 8932;          % 与下位机代码保持一致8931端口用来发命令，8932用来收数据
                h=@saveClient;
                svr  = tcpserver(ip,port, ...
                    "ConnectionChangedFcn", @(src, event)app.saveClient(src, event), ...
                    "ByteOrder","little-endian");
                %一直检测有无连接
                % pausetime=0.8;
                % value = app.Switch.Value;
                release(app.spectrumScope);
                FS=1e9;
                app.fc=app.MHzEditField_3.Value*1e6;
                app.spectrumScope = spectrumAnalyzer("SampleRate",FS, ...
                    "ViewType","spectrum", ...
                    "SpectrumType","power", ...
                    "PlotAsTwoSidedSpectrum",false, ...
                    "Method","welch", ...
                    "AveragingMethod","vbw", ...
                    "SpectrumUnits", "dBm", ...
                    "AxesScaling", "Manual", ...
                     "YLimits", [-80 +20],...
                     Span=20e6,...                     
                     CenterFrequency=app.MHzEditField_3.Value*1e6, ...
                     FrequencySpan="span-and-center-frequency");

                %设置匹配滤波器
                Rb=app.coderate.Value*1e6;
                sps=floor(FS/Rb);
                % DecimationFactor=1e9/125e6;
                DecimationFactor=1;
                rcrFilt = comm.RaisedCosineReceiveFilter(...
                    'Shape', 'Square root', ...
                    'RolloffFactor', app.rolloff_6.Value, ...
                    'FilterSpanInSymbols', 10, ...
                    'InputSamplesPerSymbol', DecimationFactor*sps, ...
                    'DecimationFactor',  DecimationFactor);
                %星座图对象
                switch app.DropDown_5.ValueIndex
                    case 1
                        Reference=pskmod(0:3, 4, 0);
                    case 2
                        Reference=qammod(0:15, 16, 'gray', 'UnitAveragePower', true) ;
                end

                ConstellationDiagram= comm.ConstellationDiagram(ShowReferenceConstellation=true, ...
                    EnableMeasurements=true,...
                    MeasurementInterval='Current display',...
                    ReferenceConstellation=Reference,...
                    EVMNormalization='Average constellation power');
                % 计算并补偿滤波器延迟
                txFilterDelay = rcrFilt.FilterSpanInSymbols/2;    % 发射滤波器延迟
                rxFilterDelay = rcrFilt.FilterSpanInSymbols/2;     % 接收滤波器延迟
                totalDelay = txFilterDelay + rxFilterDelay;        % 总延迟（符号数）

                flag= connectfunc(app,10,false);%连接超时为5s
                framenum=1;
                if flag==true

                    %设置带通滤波器  
                    index=app.Channel.ValueIndex;
                    setpassbandfilter(app,index);
                    app.Lamp.Color=[0.00 1.00 0.00]; %绿色"
                    while true
                        value=app.Switch.Value;
                        isconstellation=app.CheckBox_2.Value;%是否启用星座图
                        pause(0.0001); %暂停一会会儿，去查value
                        if(value=="开")
                            a=app.gCli;
                            data=uint8(read(app.gCli,(16*4096+8)*framenum));
                            
                            data = reshape(func30(app,data,receivechannel),[],1);
                            carry=exp(-1i*2*pi*app.MHzEditField_3.Value*1e6*(0:length(data)-1)/FS);


                            if isconstellation
                                data = data.*carry.';
                                data=data(app.EditField_21.Value:app.EditField_21.Value+8000);
                                data=reshape(data,[],1);
                                % 应用匹配滤波
                                filteredSignal = rcrFilt(data);
                                sps=sps/DecimationFactor;
                                % 抽取 (sps倍抽取)
                                rxDecimated = filteredSignal(totalDelay*sps+1:sps:end);
                                % rxDecimated= filteredSignal;
                                rxDecimated =rxDecimated /max(rxDecimated);
                                ConstellationDiagram(rxDecimated);
                                drawnow limitrate;
                                pause(.2);
                                app.spectrumScope( data);
                            else
                                app.spectrumScope( data);
                            end

                        else
                            app.Lamp.Color=[0.90 0.90 0.90]; %指示灯变回灰色
                            app.Switch.Value="关";
                            release( app.spectrumScope)
                            % relaese(ConstellationDiagram);
                            return;
                        end
                    end
                else
                    delete(svr);
                    app.Lamp.Color=[0.90 0.90 0.90]; %指示灯变回灰色
                    app.Switch.Value="关";
                end
            catch ME
                app.Lamp.Color=[0.90 0.90 0.90]; %指示灯变回灰色
                app.Switch.Value="关";
                uialert(app.UIFigure,ME.message,"提示");
            end


        end
        %%关闭频谱仪
        function closespectrum(app)
            release( app.spectrumScope);
        end
        %%产生噪声
        function out =noisegenerator(app)
        
            %% 设置参数
            npoint=4096*8;  %送给FPGA的数据个数
            FS=125e6;                
            % 生成均匀分布白噪声           
            a = -1;  % 最小值
            b = 1;   % 最大值
            % aaa=rand(size(t));
            %   txframe = a + (b-a)*rand(size(t));
            %   plot(txframe);
            %    app.spectrumScope(txframe.');                    
            signal = a + (b-a)*randi([0 1],1,npoint);
             %启用滤波器
             if app.CheckBox_5.Value==true
                 FC=app.lowpassfilter.Value*1e6;%低通滤波器截止频率
                 txframe=Lowpassfilter(app,FS,FC,signal);
             else
                 txframe=signal;
             end          
             txBaseband =txframe/max(abs(txframe));
             y= txBaseband ./max(abs( txBaseband ));

             % plot(x);
             % 量化到 int16（假设满量程 ±1 → ±32767）
             xInt = int16(round(real(y) * 32767));
             yInt = int16(round(imag(y) * 32767));
             % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
             ch0_i = xInt(:);   % I 路
             ch0_q = yInt(:); % Q 路
             ch1_i = xInt(:);   % 同上
             ch1_q =  yInt(:);

             % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
              c=int16(zeros(length(xInt),4));

            for i=1:length(xInt)
                a=[ch0_i(i),ch0_q(i),ch1_i(i),ch1_q(i)];
                c(i,:)=a;
            end

            frameInt16 = reshape(c.',[],1);
             % frameInt16 = frameInt16(:);         % 拉成一列

             % 若想变成字节流（Little-Endian）
             out = typecast(frameInt16, 'uint8');   % 长度 *2
        end

        %%产生多音信号
        function out =multitone(app)
              %% 设置参数
              npoint=4096*4;
              FS=125e6;
              %需要产生哪些频率的信号
              StartFreqdev=app.startfd.Value*1e6;    %起始频偏
              StopFreqdev=app.endfd.Value*1e6;  %MHz
              numtone=app.tonenum.Value; %多音数目
              frequencies=zeros(1,numtone);
              frequencies(1)=StartFreqdev;
              frequencies(end)=StopFreqdev;
              for i=2:length(frequencies)-1
                  frequencies(i)=frequencies(i-1)+(StopFreqdev-StartFreqdev)/(numtone-1);
              end


              %产生信号
              t=(0:npoint-1)/FS;
              signal=zeros(1,length(t));
              for i=1:length(frequencies)
                  signal=signal+cos(2*pi*frequencies(i)*t);
              end
             %启用滤波器
             
             if app.CheckBox_5.Value==true
                 FC=app.lowpassfilter.Value*1e6;%低通滤波器截止频率
                 txframe=Lowpassfilter(app,FS,FC,signal);
             else
                 txframe=signal;
             end
             % txframe=txframe-mean(txframe);
             txBaseband= txframe./max(abs( txframe));
             y= txBaseband ./max(abs( txBaseband ));

             % plot(x);
             % 量化到 int16（假设满量程 ±1 → ±32767）
             xInt = int16(round(real(y) * 32767));
             yInt = int16(round(imag(y) * 32767));
             % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
             ch0_i = xInt(:);   % I 路
             ch0_q = yInt(:); % Q 路
             ch1_i = xInt(:);   % 同上
             ch1_q =  yInt(:);
             % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
              c=int16(zeros(length(xInt),4));

            for i=1:length(xInt)
                a=[ch0_i(i),ch0_q(i),ch1_i(i),ch1_q(i)];
                c(i,:)=a;
            end

            frameInt16 = reshape(c.',[],1);

             % 若想变成字节流（Little-Endian）
             out = typecast(frameInt16, 'uint8');   % 长度 *2


        end
        %%产生FMCW信号
        function out =FMCWgenerator(app)                      
            %% 生成FMCW调制信号
             FS=125e6;
            sFMCW = phased.FMCWWaveform(SweepBandwidth=app.rolloff_5.Value*1e6,...
                SampleRate=FS, ...
                SweepDirection="Up",...
                SweepTime=app.sweeptime.Value*1e-3,...
                OutputFormat="Sweeps",...
                NumSweeps=1);
            a1=length(sFMCW());
            var=sFMCW().';
            var1=0;
            var2=0;
            c=4096*16;

            if a1>c
               var=var(1:4096*16);
            else
                var1=floor(length(sFMCW())/4096);
                var2=4096*(var1+1)-length(sFMCW());  %补零数目
                npoint =var1+var2;
               
            end


            % app.spectrumScope.SampleRate=
            % app.spectrumScope(sFMCW());

            txframe=[var zeros(1,var2) ];
            if app.CheckBox_5.Value==true
                FC=app.lowpassfilter.Value*1e6;%低通滤波器截止频率
                txframe=Lowpassfilter(app,FS,FC,txframe);
            else
                txframe;
            end
            txBaseband=txframe./max(abs(sFMCW()));
            y= txBaseband ./max(abs( txBaseband ));
            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y) * 32767));
            yInt = int16(round(imag(y) * 32767));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            ch0_i = xInt(:);   % I 路
            ch0_q = yInt(:); % Q 路
            ch1_i = xInt(:);   % 同上
            ch1_q =  yInt(:);
            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
             c=int16(zeros(length(xInt),4));

            for i=1:length(xInt)
                a=[ch0_i(i),ch0_q(i),ch1_i(i),ch1_q(i)];
                c(i,:)=a;
            end

            frameInt16 = reshape(c.',[],1);

            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2
         
           
        end
        %%产生脉冲信号
        function  out =pulse(app)
            % app.modFreq = app.fam.Value;         % 调制信号频率 (1 kHz
            FS  = 125e6;
            npoint=4096*2;
            pulseWidth  = round(app.pulsewidth1.Value*1e-6*FS);      % 20 µs
            pulsePeriod = round(app.pri1.Value*1e-3*FS);       % 1 ms
            b=round(pulseWidth/pulsePeriod*npoint);
            c=floor((npoint-b) /2);
            a=[zeros(1,c) ones(1,b) zeros(1,npoint-c-b)];
            y    =hilbert(a);
            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y) * 32767));
            yInt = int16(round(imag(y) * 32767));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            ch0_i = xInt(:);   % I 路
            ch0_q = yInt(:); % Q 路
            ch1_i = xInt(:);   % 同上
            ch1_q =  yInt(:);

            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
              c=int16(zeros(length(xInt),4));

            for i=1:length(xInt)
                a=[ch0_i(i),ch0_q(i),ch1_i(i),ch1_q(i)];
                c(i,:)=a;
            end

            frameInt16 = reshape(c.',[],1);
            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2
        end
        %%产生BPSK信号
        function  out =BPSKgennerator(app)
            %% 设置参数
             npoint=4096*8;  %送给FPGA的数据个数
             FS=125e6;
             Rb=app.rb_3.Value*1e6;
             sps=floor(FS/ Rb); %
             M=2;
             %生成的符号数目
             numsymble=floor(npoint/sps);
             numbits=numsymble*log2(M);
             s = RandStream('dsfmt19937');
             % Use the stream to generate five random numbers.

             dataIn=randi(s,[0 1],1,numbits);

            %计算需要生成多少比特
          

            %% 2. 成型滤波（根升余弦滤波器）
            Rolloff=app.rolloff_3.Value;
            rctFilt = comm.RaisedCosineTransmitFilter(...
                'Shape', 'Square root', ...
                'RolloffFactor', Rolloff, ...
                'FilterSpanInSymbols', 10, ...
                'OutputSamplesPerSymbol', sps);
         
             dataIn=reshape(dataIn,[],1);
            modSymbols = pskmod(dataIn,M,0,"gray","InputType","bit");
            % 应用成型滤波
            txBaseband = rctFilt(modSymbols);
            txFrame= txBaseband/max( abs(txBaseband));
            AM=0.7;
            y    =AM*hilbert(txFrame);
            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y) * 32767));
            yInt = int16(round(imag(y) * 32767));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            ch0_i = xInt(:);   % I 路
            ch0_q = yInt(:); % Q 路
            ch1_i = xInt(:);   % 同上
            ch1_q =  yInt(:);

            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
              c=int16(zeros(length(xInt),4));

            for i=1:length(xInt)
                a=[ch0_i(i),ch0_q(i),ch1_i(i),ch1_q(i)];
                c(i,:)=a;
            end

            frameInt16 = reshape(c.',[],1);
            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2

        end
        %%产生正弦信号
        function  out =sinwave(app)
            npoint=4096*2;%生成多少数据点
            %%%%%%%%%%%%%%%%未启用扫频%%%%%%%%%%%%%%%%%%%%%
            AMbase=0.7*ones(1,npoint);
            y    =hilbert(AMbase);
            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y) * 32767));
            yInt = int16(round(imag(y) * 32767));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            ch0_i = xInt(:);   % I 路
            ch0_q = yInt(:); % Q 路
            ch1_i = xInt(:);   % 同上
            ch1_q =  yInt(:);

            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
            c=int16(zeros(length(xInt),4));

            for i=1:length(xInt)
                a=[ch0_i(i),ch0_q(i),ch1_i(i),ch1_q(i)];
                c(i,:)=a;
            end

            frameInt16 = reshape(c.',[],1);
            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2
        end
        %%产生余弦信号
        function out =coswave(app)
            npoint=4096*2;%生成多少数据点
            %%%%%%%%%%%%%%%%未启用扫频%%%%%%%%%%%%%%%%%%%%%
            AMbase=0.7*ones(1,npoint);
            y    =hilbert(AMbase);
            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y) * 32767));
            yInt = int16(round(imag(y) * 32767));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            ch0_i = xInt(:);   % I 路
            ch0_q = yInt(:); % Q 路
            ch1_i = xInt(:);   % 同上
            ch1_q =  yInt(:);

            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
             c=int16(zeros(length(xInt),4));

            for i=1:length(xInt)
                a=[ch0_i(i),ch0_q(i),ch1_i(i),ch1_q(i)];
                c(i,:)=a;
            end

            frameInt16 = reshape(c.',[],1);

            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2
        end
        %%产生三角波信号
        function out =trianglewave(app)
            % npoint=4096*2;%生成多少数据点
            FS=125e6;
            f1=1e6;
            T=FS/f1;
            L = lcm(T, 4096);%最小公倍数
            t=0:1/FS:(L-1)/FS;              
            txframe = sawtooth(2 * pi * f1* t, 0.5); % width=0.5 时为三角波  
            pointnum=512/2;
             L = lcm(pointnum*2, 4096);%最小公倍数
            scale=1/pointnum;
            wave1=linspace(0,1-scale,pointnum);
            wave2=linspace(1,scale,pointnum);
            wave=[ wave1 wave2];
            N=L/(pointnum*2);
            bb=repmat(wave,1,N);
             txframe=reshape(bb,[],1);
            %是否启用低通滤波器
            if app.CheckBox_5.Value==true
                FC=app.lowpassfilter.Value*1e6;%低通滤波器截止频率
                txframe=Lowpassfilter(app,actualSampleRate,FC, txframe);
            else
                txframe=txframe;
            end

            AM=0.7;
            txframe=AM*txframe/max(abs(txframe));
             y    = txframe;
            % writematrix(y,"trip.csv")
            % csvwrite('trip3.csv', y);
            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = AM*int16(round(real(y) * 32767));
            yInt = 0*int16(round(imag(y) * 32767));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            ch0_i = xInt(:);   % I 路
            ch0_q = yInt(:); % Q 路
            ch1_i = xInt(:);   % 同上
            ch1_q =  yInt(:);

            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
              c=int16(zeros(length(xInt),4));

            for i=1:length(xInt)
                a=[ch0_i(i),ch0_q(i),ch1_i(i),ch1_q(i)];
                c(i,:)=a;
            end

            frameInt16 = reshape(c.',[],1);

            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2
           
            
        end
        %%产生方波信号
        function  out =  squrewave(app)
              % npoint=4096*2;%生成多少数据点
            FS=125e6;
            f1=1e6;
            T=FS/f1;
            L = lcm(T, 4096);%最小公倍数
            t=0:1/FS:(L-1)/FS;              
            % txframe = sawtooth(2 * pi * f1* t, 0.5); % width=0.5 时为三角波  
            pointnum=512/2;
             L = lcm(pointnum*2, 4096);%最小公倍数
            scale=1/pointnum;
            wave1=ones(1,pointnum);
            wave2=-ones(1,pointnum);
            wave=[ wave1 wave2];
            N=L/(pointnum*2);
            bb=repmat(wave,1,N);
            txframe=reshape(bb,[],1);
            %是否启用低通滤波器
            if app.CheckBox_5.Value==true
                FC=app.lowpassfilter.Value*1e6;%低通滤波器截止频率
                txframe=Lowpassfilter(app,actualSampleRate,FC, txframe);
            else
                txframe=txframe;
            end
            AM=0.7;
            txframe=AM*txframe/max(abs(txframe));
            y    =hilbert( txframe);
           
            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y) * 32767));
            yInt = int16(round(imag(y) * 32767));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            ch0_i = xInt(:);   % I 路
            ch0_q = yInt(:); % Q 路
            ch1_i = xInt(:);   % 同上
            ch1_q =  yInt(:);

            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
              c=int16(zeros(length(xInt),4));

            for i=1:length(xInt)
                a=[ch0_i(i),ch0_q(i),ch1_i(i),ch1_q(i)];
                c(i,:)=a;
            end

            frameInt16 = reshape(c.',[],1);

            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2
            
        end
        %%产生锯齿波信号
        function out = zigzagwave(app)
            %% 设置参数

            npoint=4096*2;%生成多少数据点
            FS=125e6;
            f1=1e6;
            t=0:1/FS:(npoint-1)/FS;
            txframe = sawtooth(2 * pi * f1 * t, 1); % width=1 时为锯齿波
            %是否启用低通滤波器
            if app.CheckBox_5.Value==true
                FC=app.lowpassfilter.Value*1e6;%低通滤波器截止频率
                txframe=Lowpassfilter(app,actualSampleRate,FC, txframe);
            else
                txframe=txframe;
            end
            AM=0.7;
            txframe=AM*txframe/max(abs(txframe));
            y    =hilbert( txframe);
            % plot(x);
            % 量化到 int16（假设满量程 ±1 → ±32767）
            xInt = int16(round(real(y) * 32767));
            yInt = int16(round(imag(y) * 32767));
            % 构造 2 通道数据：通道 0 放 x，通道 1 放 x（也可放 0）
            ch0_i = xInt(:);   % I 路
            ch0_q = yInt(:); % Q 路
            ch1_i = xInt(:);   % 同上
            ch1_q =  yInt(:);

            % 交织成 [ch0_i, ch0_q, ch1_i, ch1_q, ...]
           c=int16(zeros(length(xInt),4));

            for i=1:length(xInt)
                a=[ch0_i(i),ch0_q(i),ch1_i(i),ch1_q(i)];
                c(i,:)=a;
            end

            frameInt16 = reshape(c.',[],1);

            % 若想变成字节流（Little-Endian）
            out = typecast(frameInt16, 'uint8');   % 长度 *2
           
        end

        %%打开示波器
        function openoscillograph(app)
            %% 设置参数
            try
             receivechannel=app.DropDown_11.ValueIndex;
                %建立连接
                [~,hostname] = system('hostname');
                hostname = string(strtrim(hostname));
                ip = resolvehost(hostname,"address");
                %建立TCP连接
                %% 1. 启动服务器
                % ip   = '0.0.0.0';     % 监听所有本地地址
                port = 8932;          % 与下位机代码保持一致8931端口用来发命令，8932用来收数据
                h=@saveClient;
                svr  = tcpserver(ip,port, ...
                    "ConnectionChangedFcn", @(src, event)app.saveClient(src, event), ...
                    "ByteOrder","little-endian");
                %一直检测有无连接
                pausetime=0.8;
                % value = app.Switch.Value;
                release(app.spectrumScope);
                FS=1e9;               
                span=5000/FS;
                % scope1 = timescope(BufferLength=10000,...
                %     SampleRate=FS,...
                %     Name= '时域波形', ...
                %     TimeSpan=  span/2, ...              % 显示 1ms 的波形
                %     YLimits= [-1e-3  1e-3], ...
                %     ShowGrid= true ,...
                %     TimeSpanOverrunAction= "scroll");
                 scope1 = timescope( SampleRate=FS);
                % scope1.Trigger.Mode="auto";
                % scope1.Trigger.Type=app.DropDown_12.Value;
                % scope1.Trigger.AutoLevel=false;
                % scope1.Trigger.Level=app.EditField_20.Value;
                % scope1.Trigger.Enabled=true;
                % 总延迟（符号数）
                framenum=1;
                flag= connectfunc(app,10,false);%连接超时为5s
                if flag==true
                    %设置带通滤波器
                    index=app.Channel_2.ValueIndex;
                    setpassbandfilter(app,index)
                    app.Lamp_2.Color=[0.00 1.00 0.00]; %绿色"

                    value=app.Switch_2.Value;
                    pause(0.0001); %暂停一会会儿，去查value
                    if(value=="开")
                        % a=app.gCli;
                        % data=uint8(read(app.gCli,8*4096));
                        % data = reshape(func30(app,data,receivechannel),[],1);
                        % carry=exp(-1i*2*pi*app.MHzEditField_3.Value*1e6*(0:length(data))/FS);
                        %示波器接收部分
                        flag=0;%避免多次进入保存波型
                        savedata=[];
                        islowpass=true;
                        while true
                            pause(1e-5);
                            if(app.Switch_2.Value=="关")%停止显示波形  
                                delete(svr);                              
                                release(scope1);
                                return;
                            end
                            data=uint8(read(app.gCli,framenum*(4096*1+8)));
                            data = reshape(func30(app,data,receivechannel),[],1);
                            if islowpass  %是否使用低通滤波器
                                data=Lowpassfilter(app,FS,40*1e6,data);
                            end
                            % data=select(app,data);
                            scope1(real(data));
                            drawnow limitrate;
                            if(app.CheckBox_14==true&&flag==0) %保存波形
                                startTime = tic;      % 启动秒表
                                flag=1;
                            end
                            if flag==1 %
                                currentTime = toc(startTime);   % 得到这段代码运行了多少秒
                                app.CheckBox_14=false;
                                if(currentTime<=app.samples_7.Value)
                                    savedata=[savedata data];

                                else
                                    path=app.savepath.Value;
                                    fid = fopen( path, 'w');   % w = 写，覆盖
                                    fwrite(fid,savedata, 'double');       % 按 double（8 字节）写
                                    fclose(fid);
                                    flag=0;
                                    app.Button_28.Enable="on";
                                    savedata=[];
                                end
                            end
                        end


                    else
                        app.Lamp_2.Color=[0.90 0.90 0.90]; %指示灯变回灰色
                        app.Switch_2.Value="关";
                        release( app.spectrumScope)
                        % relaese(ConstellationDiagram);
                        return;
                    end

                else
                    delete(svr);
                    app.Lamp_2.Color=[0.90 0.90 0.90]; %指示灯变回灰色
                    app.Switch_2.Value="关";
                end
            catch ME
                app.Lamp_2.Color=[0.90 0.90 0.90]; %指示灯变回灰色
                app.Switch_2.Value="关";
                uialert(app.UIFigure,ME.message,"提示");
            end

            % 创建系统对象
            %% 设置USRP发射器和接收器


        end
        %%日志函数
        function logMessage(app, message)

            % 获取当前时间戳
            timestamp = datetime("now","Format","default");
            logLine = sprintf('[%s] %s', timestamp, message);
            if(app.count==0)
                app.logarea.Value =  logLine;
                app.logarea.scroll('bottom');
            else
                % 如果是 Text Area
                app.logarea.Value = [app.logarea.Value; logLine];
                % 自动滚动到底部
                app.logarea.scroll('bottom');
            end
            % 同时打印到 MATLAB 命令行（可选）
            % disp(logLine);
            app.count=app.count+1;
        end


        %%**********关闭时的操作，类似析构函数

        % function delete(app)
        %     % === 自定义清理开始 ===
        %     % 1. 关闭串口
        %     % if isfield(app, 's') && isvalid(app.s)
        %     %     fclose(app.s);
        %     %     delete(app.s);
        %     % end
        %     % 
        %     % % 2. 停止定时器
        %     % if isfield(app, 'timer') && isvalid(app.timer) && app.timer.Running
        %     %     stop(app.timer);
        %     %     delete(app.timer);
        %     % end
        %     % 
        %     % % 3. 关闭 TCP 资源
        %     % if isfield(app, 'server') && isvalid(app.server)
        %     %     stop(app.server);
        %     %     delete(app.server);
        %     % end
        %     % if isfield(app, 'client') && isvalid(app.client)
        %     %     fclose(app.client);
        %     %     delete(app.client);
        %     % end
        %     delete(app.gCli);
        %     delete(app.gCli1);
        % 
        %     % === 自定义清理结束 ===
        % 
        %     % 必须手动删除 UIFigure，否则窗口关不掉
        %     delete(app.UIFigure);
        % end  
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
             pause(0.2);
                app.spectrumScope = spectrumAnalyzer( ...
                "ViewType","spectrum", ...
                "SpectrumType","power", ...
                "PlotAsTwoSidedSpectrum",true, ...
                "Method","welch", ...
                "AveragingMethod","exponential", ...
                "SpectrumUnits", "dBm", ...
                "AxesScaling", "auto", ...
                "YLimits", [-80 +0], ...
                "ForgettingFactor", 0.95 );
            app.scope = timescope( ...
                'Name', 'SDR 时域波形', ...
                'TimeSpan', 1e-3, ...              % 显示 1ms 的波形
                'YLimits', [-1  1], ...
                'ShowGrid', true ,...
                'TimeSpanOverrunAction', 'wrap');   
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            % 获取SDR地址信息  模拟源
             app.Button_14.Text="获取中...";
             pause(0.01);%加强视觉效果
            devices = findsdru();
            if strcmp(devices(1).Status, 'No devices found')
                uialert(app.UIFigure, '未检测到SDR设备！请检查连接。','设备错误');
                app.Button_14.Text="获取";
            else
                app.SDRAddress_tx.Value=devices(1).SerialNum;
                app.Button_14.Text="获取";
                app.SerialNum=devices(1).SerialNum;
                app.Platform=devices(1).Platform;
            end
        end

        % Selection changed function: ButtonGroup_tx
        function ButtonGroup_txSelectionChanged(app, event)
        
            selectedButtonText = app.ButtonGroup_tx.SelectedObject.Text;
            switch selectedButtonText
                case "调幅"
                    app.AMPanel_4.Enable="on";
                    app.AMPanel_4.Visible="on";
                    app.FMPanel.Enable="off";
                    app.PulsePanel.Enable="off";
                    app.FMPanel.Visible="off";
                    app.PulsePanel.Visible="off";
                    app.CWPanel.Visible="off";
                    app.CWPanel.Enable="off";
                    app.sampleRate=app.EditField_fmfs.Value*1e6;
                    % app.samplesPerFrame=app.samplesam.Value;
                case "调频"  
                    app.AMPanel_4.Enable="off";
                    app.FMPanel.Enable="on";
                    app.PulsePanel.Enable="off";
                    app.AMPanel_4.Visible="off";
                    app.FMPanel.Visible="on";
                    app.PulsePanel.Visible="off";
                    app.CWPanel.Visible="off";
                    app.CWPanel.Enable="off";
                    app.sampleRate=app.EditField_fmfs.Value*1e6;
                    % app.samplesPerFrame=app.samplesfm.Value;
                case "脉冲"
                    app.AMPanel_4.Enable="off";
                    app.FMPanel.Enable="off";
                    app.PulsePanel.Enable="on";
                    app.AMPanel_4.Visible="off";
                    app.FMPanel.Visible="off";
                    app.PulsePanel.Visible="on";
                    app.CWPanel.Visible="off";
                    app.CWPanel.Enable="off";
                    app.sampleRate=app.EditField_pulsefs.Value*1e6;
                    % app.samplesPerFrame=app.samplespulse.Value;
                case "连续波"
                    app.AMPanel_4.Enable="off";
                    app.FMPanel.Enable="off";
                    app.PulsePanel.Enable="of";
                    app.AMPanel_4.Visible="off";
                    app.FMPanel.Visible="off";
                    app.PulsePanel.Visible="of";
                    app.CWPanel.Visible="on";
                    app.CWPanel.Enable="on";
                    app.sampleRate=app.EditField_pulsefs.Value*1e6;
                    % app.samplesPerFrame=app.samplespulse.Value;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            %频谱仪开关
            value = app.Switch.Value;   
            % selectedButtontext=app.ButtonGroup.SelectedObject.Text;
            switch value
                case "开"  
                % RadioFreqfilter(app,selectedButtontext);
                openspectrum(app);  %打开频谱仪
                case "关"
                app.Lamp.Color=[0.90 0.90 0.90]; %灰色
                closespectrum(app); %未实现代码
            end
        end

        % Button pushed function: Button_27
        function Button_27Pushed(app, event)
             % 示波器
             % 获取SDR地址信息
             app.Button_27.Text="获取中...";
             pause(0.01);%加强视觉效果
             devices = findsdru();
             if strcmp(devices(1).Status, 'No devices found')
                 uialert(app.UIFigure, '未检测到SDR设备！请检查连接。','设备错误');
                 app.Button_27.Text="获取";
             else

                 app.SDRPlatform_rx_3.Value=devices(1).SerialNum;
                 app.Button_27.Text="获取";
                 app.SerialNum=devices(1).SerialNum;
                 app.Platform=devices(1).Platform;
             end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            %示波器开关
            value = app.Switch_2.Value;
            switch value
                case "开"
                    openoscillograph(app);  %打开示波器
                case "关"
                    app.Button_28.Enable="off";
                    app.Lamp_2.Color=[0.90 0.90 0.90]; %灰色
                    % closespectrum(app); %未实现代码
            end
        end

        % Button pushed function: Button_28
        function Button_28Pushed(app, event)
            app.Button_28.Enable="off";%在执行完命令前，按钮不能再次被按下
            app.issavewave=true;  %开始采集波形
        end

        % Button pushed function: closedevice
        function closedeviceButtonPushed(app, event)
            %关闭芯片控制
             % 先清掉旧库
            try
            if libisloaded('CH347DLLA64')
                unloadlibrary CH347DLLA64;
            end

           
            % "C:\Users\admin\Desktop\电科SDR\衰减器控制\CH347DLL.H"
            %加载动态库
            var1=app.dllPath;
            var2=app.hdrPath;
            [notfound, warnings] =loadlibrary(app.dllPath, app.hdrPath, 'alias', 'mm');
            if ~isempty(notfound)
                error(char(notfound));
            end 
            %关闭设备
            devIndex  = uint32(0);          % 第 0 号设备
            Isclosed=calllib("mm","CH347CloseDevice",devIndex);            
            app.logMessage("设备关闭成功!");
            app.IsopenDevice=~Isclosed;
            app.closedevice.Enable="off";
            app.opendevice.Enable="on";
            %释放掉库
            unloadlibrary mm;
            catch  ME
                app.logMessage(sprintf("错误: %s", ME.message));
            end 

        end

        % Button pushed function: Button_30
        function Button_30Pushed2(app, event)
             %设置衰减量
            SpiI2cGpioDevIndex = uint32(0);    % ulong 对应 uint32
            oEnable = 255;              % uint8  管脚使能
            oSetDirOut = 255;            % uint8  输入/输出控制 1：输出
            oSetDataOut = 255;           % uint8  输出的数据
            if app.IsopenDevice==false
                uialert(app.UIFigure,"请先打开设备","提示","Icon","info");
                return;
            end
            try
                % 先清掉旧库
                if libisloaded('CH347DLLA64')
                    unloadlibrary CH347DLLA64;
                end
                %加载动态库
                var1=app.dllPath;
                var2=app.hdrPath;
                [notfound, warnings] =loadlibrary(app.dllPath, app.hdrPath, 'alias', 'mm1');
                if ~libisloaded('mm1')
                    uialert(app.UIFigure,"Dll未加载","提示","Icon","info");
                    return;
                else
                   % cc= libfunctions('mm1', '-full'); % 显示所有导出函数
                end
                if ~isempty(notfound)
                    error(char(notfound));
                end
                % attenuation=str2double(app.Attenuation.Value); %衰减值
                attenuation= app.dBEditField_7.Value;
               
                %衰减的动态范围大约36dB
                attenuationrange=36;
                %转化为128个状态中的1个
                oSetDataOut=round(attenuation/attenuationrange*128); 
                %设置衰减
                if   opdefunc34(app,oSetDataOut)

                    app.logMessage("设置衰减"+  attenuation+"dB成功!"); %输出日志信息
                end
                %% 4. （可选）释放库
                unloadlibrary mm1;
            catch  ME  %捕获异常
                app.logMessage(sprintf("错误: %s", ME.message));
            end
        end

        % Value changed function: Button_starttransmit
        function Button_starttransmitValueChanged(app, event)
            %模拟源仪器连接**********
            app.ALC=app.ALCCheckBox.Value;
            IsALC=app.ALC;
            try
                %
                app.Button_starttransmit.Enable="off";
                ip = "192.168.1.111";
                if  app.Button_starttransmit.Value=="开"
                    %仪器连接
                    InstrumentConnection(app,ip,8931,app.Button_starttransmit,app.Lamp_3);
                    if IsALC==true
                        InstrumentConnection(app,ip,8932,app.Button_starttransmit,app.Lamp_3);
                    end
                    %一直检测有无连接
                    IsConnected= connectfunc(app,app.sEditField.Value,app.ALC);%连接超时为4s
                    if IsConnected==false
                        Switcher(app, app.Button_starttransmit, app.Lamp_3,"关");   %模拟源
                        Switcher(app, app.Button_starttransmit_4, app.Lamp_5,"关");  %矢量源
                    else
                        Switcher(app, app.Button_starttransmit, app.Lamp_3,"开");     %模拟源
                         Switcher(app, app.Button_starttransmit_4, app.Lamp_5,"开");  %矢量源
                    end
                    app.Button_starttransmit.Enable="on";
                    return;
                end
                if  app.Button_starttransmit.Value=="关"
                    InstrumentDisConnection(app,8931);
                    if IsALC==true
                        InstrumentDisConnection(app,8932);
                    end                 
                    Switcher(app, app.Button_starttransmit, app.Lamp_3,"关");
                    Switcher(app, app.Button_starttransmit_4, app.Lamp_5,"关");  %矢量源
                end
            catch ME
                uialert(app.UIFigure,ME.message,"提示");
                Switcher(app, app.Button_starttransmit, app.Lamp_3,"关");
                Switcher(app, app.Button_starttransmit_4, app.Lamp_5,"关");  %矢量源
            end
        end

        % Button pushed function: opendevice
        function opendeviceButtonPushed2(app, event)
              %打开控制芯片
            % 先清掉旧库
             try
            if libisloaded('CH347DLLA64')
                unloadlibrary CH347DLLA64;
            end

           
            % "C:\Users\admin\Desktop\电科SDR\衰减器控制\CH347DLL.H"
            var1=app.dllPath;
            var2=app.hdrPath;
            [notfound, warnings] =loadlibrary(app.dllPath, app.hdrPath, 'alias', 'mm');
            if ~isempty(notfound)
                error(char(notfound));
            end       
            % 显式指定参数类型（关键！）
            % SpiI2cGpioDevIndex = uint32(0);    % ulong 对应 uint32
            % oEnable = 255;              % uint8
            % oSetDirOut = 255;            % uint8
            % oSetDataOut = 0;           % uint8
          
            % if ~libisloaded('mm')
            %     error('DLL 未加载！');
            % else
            %     libfunctions('mm', '-full'); % 显示所有导出函数
            % end

            % 2. 调用 CH347OpenDevice
            devIndex  = uint32(0);          % 第 0 号设备
            hDevPtr   = calllib('mm', 'CH347OpenDevice', devIndex);
            app.logMessage("设备打开成功!");
            app.IsopenDevice=true;
            app.closedevice.Enable="on";
            app.opendevice.Enable="off";
            % %
            % RetVal = calllib('mm', 'CH347GPIO_Set', ...
            %     SpiI2cGpioDevIndex, ...
            %     oEnable, ...
            %     oSetDirOut, ...
            %     oSetDataOut);
            % %关闭设备
            % % Isclosed=calllib("mm","CH347CloseDevice",devIndex);
            % 
            % % 3. 检查结果
            % if RetVal == 1
            %     disp('GPIO 设置成功');
            % else
            %     disp('GPIO 设置失败');
            % end
            % % 4. （可选）释放库
            unloadlibrary mm;            
            catch  ME
                app.logMessage(sprintf("错误:设备打开失败 %s", ME.message));
            end 
        end

        % Button pushed function: Button_36
        function Button_36Pushed(app, event)
             %设置接收通道
             SpiI2cGpioDevIndex = uint32(0);    % ulong 对应 uint32
            oEnable = 255;              % uint8  管脚使能
            oSetDirOut = 255;            % uint8  输入/输出控制 1：输出
            oSetDataOut = 255;           % uint8  输出的数据
            if app.IsopenDevice==false
                uialert(app.UIFigure,"请先打开设备","提示","Icon","info");
                return;
            end
            try
                % 先清掉旧库
                if libisloaded('CH347DLLA64')
                    unloadlibrary CH347DLLA64;
                end
                %加载动态库
                var1=app.dllPath;
                var2=app.hdrPath;
                [notfound, warnings] =loadlibrary(app.dllPath, app.hdrPath, 'alias', 'mm');
                if ~libisloaded('mm')
                    uialert(app.UIFigure,"Dll未加载","提示","Icon","info");
                    return;
                else
                   cc= libfunctions('mm', '-full'); % 显示所有导出函数
                end
                if ~isempty(notfound)
                    error(char(notfound));
                end
               
                hDevPtr   = calllib('mm', 'CH347OpenDevice',0);%打开设备
               
                channel=app.Channel.Value; %通道
                bitArray=[1 0 0 0 0 0 0 0];
                switch channel  %设置衰减值
                    case "DC-40MHz"
                        bitArray(5)=1;
                        oSetDataOut=bi2de(bitArray,"left-msb");
                        if   calllib('mm', 'CH347GPIO_Set', ...
                                SpiI2cGpioDevIndex, ...
                                oEnable, ...
                                oSetDirOut, ...
                                oSetDataOut)
                           
                            app.logMessage("设置通道"+  channel+"成功!"); %输出日志信息
                        end
                    case "500-850MHz"
                        bitArray(8)=1;
                        bitArray(6)=1;
                        oSetDataOut=bi2de(bitArray,"left-msb");
                        if   calllib('mm', 'CH347GPIO_Set', ...
                                SpiI2cGpioDevIndex, ...
                                oEnable, ...
                                oSetDirOut, ...
                                oSetDataOut)
                             app.logMessage("设置通道"+  channel+"成功!"); %输出日志信息
                        end
                 case "1100-1450MHz"
                     bitArray(7)=1;
                     
                     oSetDataOut=bi2de(bitArray,"left-msb");
                     if   calllib('mm', 'CH347GPIO_Set', ...
                             SpiI2cGpioDevIndex, ...
                             oEnable, ...
                             oSetDirOut, ...
                             oSetDataOut)
                         app.logMessage("设置通道"+  channel+"成功!"); %输出日志信息
                     end
                       
                       
                end
                %释放掉库
                unloadlibrary mm;
            catch  ME  %捕获异常
                app.logMessage(sprintf("错误: %s", ME.message));
            end
        end

        % Button down function: ButtonGroup
        function ButtonGroupButtonDown(app, event)
            RadioFreqfilter(app,selectedButtontext);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged2(app, event)
            selectedButtontext = app.ButtonGroup.SelectedObject.Text;
            RadioFreqfilter(app,selectedButtontext);
        end

        % Button pushed function: Button_38
        function Button_38Pushed(app, event)
            %获取IP地址和端口号
              %tcpip传输
              try
            svr  = tcpserver(ip,port, ...
                "ConnectionChangedFcn", @saveClient, ...
                "ByteOrder","little-endian");
            if ~isempty(app.gCli) && isvalid(app.gCli)
                app.Lamp_4.Color=[0.00,1.00,0.00];  %绿色
                app.Button_38.Text="关闭";
            end
              catch ME
                 return;
              end
            
        end

        % Button pushed function: Button_37
        function Button_37Pushed(app, event)
            %tcpip传输

            if ~isempty(app.gCli) && isvalid(app.gCli)
                % 示例：键盘输入一行 → 转成 32 字节帧
                % key = input('输入要发的 32 字节十六进制字符串（无空格）或直接回车跳过：','s');
                % if ~isempty(key) && mod(length(key),2)==0
                % frame = uint8(sscanf(key,'%2x',[]));
                % if numel(frame)==32
                data=wavegenerate(app);
                frame = pack_cmd(app,8, data);
                var=app.gCli;
                write(app.gCli, frame);
                pause(1);
                % fprintf('已发送：');
                % disp(frame');
                % end
                % end
            else
                return;
            end


        end

        % Value changed function: CheckBox_8
        function CheckBox_8ValueChanged(app, event)
            %启用AM信号源
            value = app.CheckBox_8.Value;
            if value==true
                %频率源                  
                app.Panel_49.Enable="off";
                %脉冲源             
                app.Panel_48.Enable="off";
                %连续波
                app.Panel_46.Enable="off";
            end
            if value==false
                %频率源             
                app.Panel_49.Enable="on";
                %脉冲源              
                 app.Panel_48.Enable="on";
                %连续波
                app.Panel_46.Enable="on";
            end
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            %启用FM信号源
            value = app.CheckBox_9.Value;
            if value==true
                %AM源
                app.Panel_47.Enable="off";
                %脉冲源
                app.Panel_48.Enable="off";
                %连续波
                app.Panel_46.Enable="off";
            end
            if value==false
                %AM源
                app.Panel_47.Enable="on";
                %脉冲源
                app.Panel_48.Enable="on";
                %连续波
                app.Panel_46.Enable="on";
            end
        end

        % Value changed function: CheckBox_10
        function CheckBox_10ValueChanged(app, event)
            %启用pulse信号源
            value = app.CheckBox_10.Value;
            if value==true
                %AM源
                app.Panel_47.Enable="off";
                %FM源
                app.Panel_49.Enable="off";
                %连续波
                app.Panel_46.Enable="off";
            end
            if value==false
                %AM源
                app.Panel_47.Enable="on";
                %FM源
                app.Panel_49.Enable="on";
                %连续波
                app.Panel_46.Enable="on";
            end
        end

        % Value changed function: Button_starttransmit_4
        function Button_starttransmit_4ValueChanged(app, event)
            %
            %矢量源仪器连接
            try
                %
                app.Button_starttransmit_4.Enable="off";
                ip = "192.168.1.111";
                %打开仪器
                if  app.Button_starttransmit_4.Value=="开"
                    %仪器连接
                    InstrumentConnection(app,ip,8931,app.Button_starttransmit_4,app.Lamp_5);
                    %一直检测有无连接
                    IsConnected= connectfunc(app,app.sEditField.Value,false);%连接超时为4s
                    if IsConnected==false
                        Switcher(app, app.Button_starttransmit_4, app.Lamp_5,"关"); %矢量源
                        Switcher(app, app.Button_starttransmit, app.Lamp_3,"关");   %模拟源

                    else
                        Switcher(app, app.Button_starttransmit_4, app.Lamp_5,"开");
                        Switcher(app, app.Button_starttransmit, app.Lamp_3,"开");   %模拟源
                    end
                    app.Button_starttransmit_4.Enable="on";
                    return;
                end
                %关闭仪器
                if  app.Button_starttransmit_4.Value=="关"
                    InstrumentDisConnection(app,8931);
                    Switcher(app, app.Button_starttransmit_4, app.Lamp_5,"关");
                    Switcher(app, app.Button_starttransmit, app.Lamp_3,"关");   %模拟源
                end
            catch ME
                uialert(app.UIFigure,ME.message,"提示");
                Switcher(app, app.Button_starttransmit_4, app.Lamp_5,"关");
                Switcher(app, app.Button_starttransmit, app.Lamp_3,"关");   %模拟源
            end
        end

        % Value changed function: Button_starttransmit_5
        function Button_starttransmit_5ValueChanged(app, event)
           
            %判断是否启用了信号发生器或则函数发生器
            if app.CheckBox_12.Value==false&&app.CheckBox_13.Value==false&&app.Button_starttransmit_5.Value=="开"
                  uialert(app.UIFigure,"请选择使用'信号仿真模块'或者'函数仿真模块'","提示");
                  app.DropDown_13.Value="关";
                  return;
            end
            %任意波
            [~,hostname] = system('hostname');
            hostname = string(strtrim(hostname));
            ip = resolvehost(hostname,"address");            
            %建立TCP连接
            %% 1. 启动服务器
            % ip   = '0.0.0.0';     % 监听所有本地地址
            port = 8931;          % 与下位机代码保持一致8931端口用来发命令，8932用来收数据
            h=@saveClient;
            svr  = tcpserver(ip,port, ...
                "ConnectionChangedFcn", @(src, event)app.saveClient(src, event), ...
                "ByteOrder","little-endian");
            %一直检测有无连接
           pausetime=0.8;
           value = app.Button_starttransmit_5.Value;
            %任意波发生器
            try
                if value=="开"       %打开源
                    flag= connectfunc(app,10,false);%连接超时为5s
                    if flag==true
                        app.Lamp_6.Color=[0.00 1.00 0.00]; %绿色"
                         var="";
                         if app.CheckBox_12.Value==true
                             var=app.TabGroup2.SelectedTab.Title;
                         else
                             var="函数";
                         end
                        switch var
                            case "噪声"
                                out= noisegenerator(app);
                                frame=pack_cmd(app,8, out);
                                write(app.gCli, frame);
                                channel=strtrim(string(app.DropDown_9.Value));
                                channelflag=1;
                                if channel=="通道2"

                                    channelflag=2;
                                end
                                %设置频率
                                fre_frame=pack_cmd(app,11, app.fc_11.Value*1e6,channelflag);
                                pause(pausetime);
                                write(app.gCli, fre_frame);

                            case "多音信号"
                                out=  multitone(app);
                                frame=pack_cmd(app,8, out);
                                write(app.gCli, frame);
                                channel=strtrim(string(app.DropDown_9.Value));
                                channelflag=1;
                                if channel=="通道2"

                                    channelflag=2;
                                end
                                %设置频率
                                fre_frame=pack_cmd(app,11, app.fc_11.Value*1e6,channelflag);
                                pause(pausetime);
                                write(app.gCli, fre_frame);

                            case "FMCW"
                                out=   FMCWgenerator(app);
                                frame=pack_cmd(app,8, out);
                                write(app.gCli, frame);
                                channel=strtrim(string(app.DropDown_9.Value));
                                channelflag=1;
                                if channel=="通道2"

                                    channelflag=2;
                                end
                                %设置频率
                                fre_frame=pack_cmd(app,11, app.fc_11.Value*1e6,channelflag);
                                pause(pausetime);
                                write(app.gCli, fre_frame);

                            case "脉冲"
                                out=   pulse(app);
                                frame=pack_cmd(app,8, out);
                                write(app.gCli, frame);
                                channel=strtrim(string(app.DropDown_9.Value));
                                channelflag=1;
                                if channel=="通道2"

                                    channelflag=2;
                                end
                                %设置频率
                                fre_frame=pack_cmd(app,11, app.fc_11.Value*1e6,channelflag);
                                pause(pausetime);
                                write(app.gCli, fre_frame);

                            case "BPSK"
                                out=    BPSKgennerator(app);
                                frame=pack_cmd(app,8, out);
                                write(app.gCli, frame);
                                channel=strtrim(string(app.DropDown_9.Value));
                                channelflag=1;
                                if channel=="通道2"

                                    channelflag=2;
                                end
                                %设置频率
                                fre_frame=pack_cmd(app,11, app.fc_11.Value*1e6,channelflag);
                                pause(pausetime);
                                write(app.gCli, fre_frame);

                            case "函数"
                                wave=app.DropDown_13.Value;
                                switch wave
                                    case "正弦波"
                                        out=    sinwave(app);
                                        frame=pack_cmd(app,8, out);
                                        write(app.gCli, frame);
                                        channel=strtrim(string(app.DropDown_9.Value));
                                        channelflag=1;
                                        if channel=="通道2"

                                            channelflag=2;
                                        end
                                        %设置频率
                                        fre_frame=pack_cmd(app,11, app.fc_11.Value*1e6,channelflag);
                                        pause(pausetime);
                                        write(app.gCli, fre_frame);

                                    case "余弦波"
                                        out=   coswave(app);
                                        frame=pack_cmd(app,8, out);
                                        write(app.gCli, frame);
                                        channel=strtrim(string(app.DropDown_9.Value));
                                        channelflag=1;
                                        if channel=="通道2"

                                            channelflag=2;
                                        end
                                        %设置频率
                                        fre_frame=pack_cmd(app,11, app.fc_11.Value*1e6,channelflag);
                                        pause(pausetime);
                                        write(app.gCli, fre_frame);

                                    case "三角波"
                                        out=   trianglewave(app);
                                        frame=pack_cmd(app,8, out);
                                        write(app.gCli, frame);
                                         A=read(app.gCli,length(frame));
                                          write(app.gCli, frame);
                                         A=read(app.gCli,length(frame));
                                        channel=strtrim(string(app.DropDown_9.Value));
                                        channelflag=1;
                                        if channel=="通道2"

                                            channelflag=2;
                                        end
                                        %设置频率
                                        fre_frame=pack_cmd(app,11, app.fc_11.Value*1e6,channelflag);
                                        pause(pausetime);
                                        write(app.gCli, fre_frame);
                                        A=read(app.gCli,length(fre_frame));
                                    case "方波"
                                        out=   squrewave(app);
                                        frame=pack_cmd(app,8, out);
                                        write(app.gCli, frame);
                                        channel=strtrim(string(app.DropDown_9.Value));
                                        channelflag=1;
                                        if channel=="通道2"

                                            channelflag=2;
                                        end
                                        %设置频率
                                        fre_frame=pack_cmd(app,11, app.fc_11.Value*1e6,channelflag);
                                        pause(pausetime);
                                        write(app.gCli, fre_frame);

                                    case "锯齿波"
                                        out=   zigzagwave(app);
                                        frame=pack_cmd(app,8, out);
                                        write(app.gCli, frame);
                                        channel=strtrim(string(app.DropDown_9.Value));
                                        channelflag=1;
                                        if channel=="通道2"

                                            channelflag=2;
                                        end
                                        %设置频率
                                        fre_frame=pack_cmd(app,11, app.fc_11.Value*1e6,channelflag);
                                        pause(pausetime);
                                        write(app.gCli, fre_frame);
                                end
                        end
                        % switch  comboxvalueIndex
                        %     case 1     %"QPSK"
                        %          out= QPSKTransmit(app);
                        %         frame=pack_cmd(app,8, out);
                        %         write(app.gCli, frame);
                        %         channel=app.DropDown_7.Value;
                        %         channelflag=1;
                        %         if channel=="通道2"
                        %
                        %             channelflag=2;
                        %         end
                        %
                        %         %设置频率
                        %         fre_frame=pack_cmd(app,11, app.fc_12.Value*1e6,channelflag);
                        %         pause(pausetime);
                        %         write(app.gCli, fre_frame);
                        %     case  2    %16QAM
                        %         out= QAMTransmit_16(app);
                        %         frame=pack_cmd(app,8, out);
                        %         write(app.gCli, frame);
                        %         channel=app.DropDown_7.Value;
                        %         channelflag=1;
                        %         if channel=="通道2"
                        %
                        %             channelflag=2;
                        %         end
                        %
                        %         %设置频率
                        %         fre_frame=pack_cmd(app,11, app.fc_12.Value*1e6,channelflag);
                        %         pause(pausetime);
                        %         write(app.gCli, fre_frame);
                        %
                        %     % case 2
                        %     %     FSK_2Transmit(app);
                        %     %     %     16QAMTransmit();
                        % end
                    else
                        app.Button_starttransmit_5.Value="关";
                        app.Lamp_6.Color=[0.80 0.80 0.80];%灰色
                        return;
                    end
                elseif value=="关"
                    flag=disconnectfunc(app,5);
                    if  flag==true
                        app.Button_starttransmit_5.Value="关";
                        app.Lamp_6.Color=[0.80 0.80 0.80];%灰色
                        return;
                    end
                end
            catch ME
                uialert(app.UIFigure,"出错："+ME.message,"提示");
                app.Button_starttransmit_5.Value="关";
                app.Lamp_6.Color=[0.80 0.80 0.80];%灰色
                return;
            end


        end

        % Button pushed function: Button_42
        function Button_42Pushed(app, event)
           results = opdefunc34(app,255);
            
        end

        % Value changed function: CheckBox_12
        function CheckBox_12ValueChanged(app, event)
            value = app.CheckBox_12.Value;
            if value==true
                app.Panel_56.Enable=false;
            else
                app.Panel_56.Enable=true;
            end
        end

        % Value changed function: CheckBox_13
        function CheckBox_13ValueChanged(app, event)
            value = app.CheckBox_13.Value;            
            if value==true
                app.CheckBox_12.Enable="off";
            else
                app.CheckBox_12.Enable="on";
            end 
        end

        % Value changed function: CheckBox_15
        function CheckBox_15ValueChanged(app, event)
            %启用扫频
            value = app.CheckBox_15.Value;         
            if value==true
                %频率源                  
                app.Panel_49.Enable="off";
                %脉冲源             
                app.Panel_48.Enable="off";
                %连续波
                app.Panel_47.Enable="off";
            end
            if value==false
                %频率源             
                app.Panel_49.Enable="on";
                %脉冲源              
                 app.Panel_48.Enable="on";
                %连续波
                app.Panel_47.Enable="on";
            end
        end

        % Value changed function: CheckBox_16
        function CheckBox_16ValueChanged(app, event)
            value = app.CheckBox_16.Value;
            if value==true
                app.CheckBox_17.Value=false;
                app.CheckBox_17.Enable="off";
            else
                 app.CheckBox_17.Enable="on";
            end
        end

        % Value changed function: CheckBox_17
        function CheckBox_17ValueChanged(app, event)
            value = app.CheckBox_17.Value;             
            if value==true
                app.CheckBox_16.Value=false;
                app.CheckBox_16.Enable="off";
            else
                 app.CheckBox_16.Enable="on";
            end
        end

        % Value changed function: CheckBox_18
        function CheckBox_18ValueChanged(app, event)
            value = app.CheckBox_18.Value;           
            if value==true
                app.CheckBox_19.Value=false;
                app.CheckBox_19.Enable="off";
            else
                 app.CheckBox_19.Enable="on";
            end
        end

        % Button pushed function: Button_45
        function Button_45Pushed(app, event)
            %波形下载
            try
                if app.Button_starttransmit.Value~="开"
                    uialert(app.UIFigure,"请先连接仪器","提示");
                    return;
                end

                app.Button_45.Enable="off";
                app.Button_45.Text="波形下载中...";

                source = sourcechoose(app);
                switch source
                    case "AM"
                        %选择通道
                        channelflag=app.DropDown.ValueIndex;                       
                        out= AMTransmitfunc(app,channelflag);
                        % %设置频率
                        freq=app.EditField_22.Value*0e-6;
                        fre_frame=pack_cmd(app,11, freq,channelflag);
                        WriteCommand(app,"频率",fre_frame);
                        %波形写入
                        WaveWrite(app,out,4096*28,app.Button_45);
                        % %设置频率
                        freq=app.EditField_22.Value*5e6;
                        fre_frame=pack_cmd(app,11, freq,channelflag);
                        WriteCommand(app,"频率",fre_frame);
                    case "FM"
                        %选择通道
                        channelflag=app.DropDown.ValueIndex;                      
                        out=FMTransmitfunc(app,channelflag);
                          %将频率置为0，保证后面输入的是基带数据
                        fre_frame=pack_cmd(app,11, 0e6,channelflag);
                        WriteCommand(app,"频率",fre_frame);
                         %波形写入
                        WaveWrite(app,out,4096*28,app.Button_45);
                        % %设置频率
                        freq=app.EditField_22.Value*5e6;
                        fre_frame=pack_cmd(app,11, freq,channelflag);
                        WriteCommand(app,"频率",fre_frame);
                       

                    case "Pulse"
                        %选择通道
                        channelflag=app.DropDown.ValueIndex;                       
                        out=PulseTransmitfunc(app,channelflag);
                         %将频率置为0，保证后面输入的是基带数据
                        fre_frame=pack_cmd(app,11, 0e6,channelflag);
                        WriteCommand(app,"频率",fre_frame);
                         %波形写入
                        WaveWrite(app,out,4096*28,app.Button_45);
                        % %设置频率
                        freq=app.EditField_22.Value*5e6;
                        fre_frame=pack_cmd(app,11, freq,channelflag);
                        WriteCommand(app,"频率",fre_frame);

                    case "CW"  %连续波
                        %选择通道
                        channel=app.DropDown.ValueIndex;
                        channelflag= channel;
                        out =CWTransmitfunc(app,channelflag);
                        value1=app.CheckBox_15.Value;
                        if value1==false  %定频
                            %波形写入
                            WaveWrite(app,out,4096*28,app.Button_45);
                            %设置频率
                            freq=app.EditField_22.Value*5e6;
                            fre_frame=pack_cmd(app,11, freq,channelflag);
                            WriteCommand(app,"频率",fre_frame);

                            %设置衰减
                            if app.ALCCheckBox.Value==false
                                app.Button_45.Enable="on";
                                app.Button_45.Text="波形下载";
                                return;
                            end
                            gainframe=pack_cmd(app,15, app.ALCDropDown.ValueIndex,9);%第三个参数为1:通道1，2：通道2，3:通道1和2;  15是发射，16是接收
                            write(app.gCli, gainframe);
                            A=read(app.gCli,length(gainframe));
                            var=0;%开始时让增益处于最大
                            if ~func36(app,var)
                                uialert(app.UIFigure,"大衰减设置失败","提示");
                            end


                            pause(0.1);
                            %%回读数据ALC
                            data=uint8(read(app.gCli1,16*4096));
                            flush(app.gCli1);
                            detectpower=bytes2double(app,data);
                            if detectpower<-5&&ALC==true
                                uialert(app.UIFigure,"当前衰减器范围无法调节到指定功率","警告");
                                app.Lamp_3.Color=[0.80 0.80 0.80];%灰色
                                app.Button_starttransmit.Value="关";
                                app.Button_starttransmit.Enable="on";
                            end
                            while ALC==true
                                if detectpower>-5
                                    %重新设置衰减
                                    resetattenuation(app,channel,detectpower);
                                    flush(app.gCli1);
                                    pause(1.2);
                                    data=uint8(read(app.gCli1,16*4096));
                                    detectpower=bytes2double(app,data);
                                    % detectpower=bytes2double(app,data);
                                else
                                    break;
                                end

                            end
                        end
                        if value1==true %扫频
                            channelflag=app.DropDown.ValueIndex;                           
                            endfreq=app.Stopf.Value;
                            freq=app.starf_tx.Value;
                            freqs=[];
                            %
                            while  endfreq>freq
                                freqs=[freqs freq ];
                                freq=freq+app.stepf_tx.Value;
                            end
                            %
                            freqs=[freqs endfreq ];   
                            fre_frame=pack_cmd(app,11, 0*1e6,channelflag);
                            WriteCommand(app,"频率",fre_frame);
                            %写入波形
                            WaveWrite(app,out,4096*28,app.Button_45);
                            app.Button_45.Enable="on";
                            app.Button_45.Text="波形下载";                           
                            while true
                                for i=1:length(freqs)
                                    fre_frame=pack_cmd(app,11, freqs(i)*1e6,channelflag);
                                    if app.CheckBox_15.Value==false
                                        return;
                                    end
                                    try
                                        WriteCommand(app,"频率",fre_frame);
                                        % write(app.gCli, fre_frame);
                                        % A=read(app.gCli,length(fre_frame));
                                        pause(app.stepf_tx_2.Value*1e-3);
                                    catch  ME
                                        fre_frame=pack_cmd(app,11, 0*1e6,channelflag);
                                        WriteCommand(app,"频率",fre_frame);
                                        return;
                                    end
                                end
                            end


                        end
                end

                %成功执行完播放流程
                app.Button_45.Enable="on";
                app.Button_45.Text="波形下载";
            catch ME
                WaveSendFialed(app,app.Button_45,ME); %波形下发失败
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          %%关闭程序时调用，类似于析构函数
            delete(app)
            TCPServers=tcpserverfind;
            if ~isempty( TCPServers)
                for i=1:length(TCPServers)
                    delete(TCPServers(i));
                end
            end

        end

        % Button pushed function: Button_46
        function Button_46Pushed(app, event)
            %矢量源信号波形下载
            try
                comboxvalueIndex=app.DropDown_3.ValueIndex;
                if app.Button_starttransmit_4.Value~="开"
                    uialert(app.UIFigure,"请先连接仪器","提示");
                    return;
                end

                app.Button_46.Enable="off";
                app.Button_46.Text="波形下载中...";
                switch  comboxvalueIndex
                    case 1     %"QPSK"
                        %选择通道
                        channelflag=app.DropDown_7.ValueIndex;                       
                        out= QPSKTransmit(app,channelflag);
                        % %设置频率
                        freq=app.EditField_23.Value*0e-6;
                        fre_frame=pack_cmd(app,11, freq,channelflag);
                        WriteCommand(app,"频率",fre_frame);
                        %波形写入
                        WaveWrite(app,out,4096*28,app.Button_46);
                        % %设置频率
                        freq=app.EditField_23.Value*5e6;
                        fre_frame=pack_cmd(app,11, freq,channelflag);
                        WriteCommand(app,"频率",fre_frame);
                    case  2    %16QAM
                        %选择通道
                        channelflag=app.DropDown_7.ValueIndex;                      
                        out= QAMTransmit_16(app,channelflag);
                        % %设置频率
                        freq=app.EditField_23.Value*0e-6;
                        fre_frame=pack_cmd(app,11, freq,channelflag);
                        WriteCommand(app,"频率",fre_frame);
                        %波形写入
                        WaveWrite(app,out,4096*28,app.Button_46);
                        % %设置频率
                        freq=app.EditField_23.Value*5e6;
                        fre_frame=pack_cmd(app,11, freq,channelflag);
                        WriteCommand(app,"频率",fre_frame);
                end      
                %成功执行完播放流程
                app.Button_46.Enable="on";
                app.Button_46.Text="波形下载";
                return;
            catch ME
                WaveSendFialed(app,app.Button_46,ME); %波形下发失败
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '模拟及脉冲信号源';
            app.Panel_43 = uipanel(app.Tab);
            app.Image6 = uiimage(app.Panel_43);
            app.Image6.ImageSource = fullfile(pathToMLAPP, '模拟源-1105.png');
            app.Panel_46 = uipanel(app.Panel_43);
            app.PRILabel_3 = uilabel(app.Panel_46);
            app.PRILabel_3.Text = '起始频率(MHz)：';
            app.starf_tx = uieditfield(app.Panel_46, 'numeric');
            app.starf_tx.Value = 1;
            app.PRILabel_4 = uilabel(app.Panel_46);
            app.PRILabel_4.Text = '终止频率(MHz)：';
            app.Stopf = uieditfield(app.Panel_46, 'numeric');
            app.Stopf.Value = 3;
            app.PRILabel_5 = uilabel(app.Panel_46);
            app.PRILabel_5.Text = '频率步进(MHz)：';
            app.stepf_tx = uieditfield(app.Panel_46, 'numeric');
            app.stepf_tx.Value = 1;
            app.PRILabel_6 = uilabel(app.Panel_46);
            app.PRILabel_6.Text = '驻留时间(ms)：';
            app.stepf_tx_2 = uieditfield(app.Panel_46, 'numeric');
            app.stepf_tx_2.Value = 1;
            app.CheckBox_15 = uicheckbox(app.Panel_46);
            app.CheckBox_15.ValueChangedFcn = createCallbackFcn(app, @CheckBox_15ValueChanged, true);
            app.CheckBox_15.Text = '启用扫频';
            app.Panel_45 = uipanel(app.Panel_43);
            app.Panel_45.Title = '硬件平台设置';
            app.Label_22 = uilabel(app.Panel_45);
            app.Label_22.Text = '发射通道：';
            app.DropDown = uidropdown(app.Panel_45);
            app.DropDown.Items = {'       通道1', '       通道2', '  通道1和通道2'};
            app.DropDown.Value = '       通道1';
            app.Label_101 = uilabel(app.Panel_45);
            app.Label_101.Text = '仪器连接';
            app.Button_starttransmit = uiswitch(app.Panel_45, 'slider');
            app.Button_starttransmit.Items = {'关', '开'};
            app.Button_starttransmit.ValueChangedFcn = createCallbackFcn(app, @Button_starttransmitValueChanged, true);
            app.Button_starttransmit.Value = '关';
            app.ALCDropDownLabel = uilabel(app.Panel_45);
            app.ALCDropDownLabel.Text = 'ALC通道：';
            app.ALCDropDown = uidropdown(app.Panel_45);
            app.ALCDropDown.Items = {'       通道1', '       通道2'};
            app.ALCDropDown.Value = '       通道2';
            app.Lamp_3 = uilamp(app.Panel_45);
            app.Button_45 = uibutton(app.Panel_45, 'push');
            app.Button_45.ButtonPushedFcn = createCallbackFcn(app, @Button_45Pushed, true);
            app.Button_45.Text = '波形下载';
            app.sEditFieldLabel = uilabel(app.Panel_45);
            app.sEditFieldLabel.Text = '连接超时时间(s）';
            app.sEditField = uieditfield(app.Panel_45, 'numeric');
            app.sEditField.Value = 10;
            app.ALCCheckBox = uicheckbox(app.Panel_43);
            app.ALCCheckBox.Text = {'启用'; 'ALC'};
            app.dBLabel_8 = uilabel(app.Panel_43);
            app.dBLabel_8.Text = {'测量值'; '(dB)'};
            app.dBEditField_3 = uieditfield(app.Panel_43, 'numeric');
            app.dBEditField_3.ValueDisplayFormat = '%.3f';
            app.dBEditField_3.Editable = 'off';
            app.Panel_47 = uipanel(app.Panel_43);
            app.Label_11 = uilabel(app.Panel_47);
            app.Label_11.Text = {'调制频率:'; '   (MHz)'};
            app.fam = uieditfield(app.Panel_47, 'numeric');
            app.fam.Value = 1;
            app.Label_102 = uilabel(app.Panel_47);
            app.Label_102.Text = '调制指数：';
            app.fam_2 = uieditfield(app.Panel_47, 'numeric');
            app.fam_2.Limits = [1e-05 1];
            app.fam_2.Value = 0.5;
            app.CheckBox_8 = uicheckbox(app.Panel_47);
            app.CheckBox_8.ValueChangedFcn = createCallbackFcn(app, @CheckBox_8ValueChanged, true);
            app.CheckBox_8.Text = '幅度调制源';
            app.Panel_49 = uipanel(app.Panel_43);
            app.MHzEditFieldLabel = uilabel(app.Panel_49);
            app.MHzEditFieldLabel.Text = {'信号频率：'; '(MHz)'};
            app.MHzEditField = uieditfield(app.Panel_49, 'numeric');
            app.MHzEditField.Value = 5;
            app.CheckBox_9 = uicheckbox(app.Panel_49);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '频率调制源';
            app.Label_103 = uilabel(app.Panel_49);
            app.Label_103.Text = '调频指数：';
            app.ffm_3 = uieditfield(app.Panel_49, 'numeric');
            app.ffm_3.Limits = [1e-05 Inf];
            app.ffm_3.Value = 1;
            app.Panel_48 = uipanel(app.Panel_43);
            app.PRILabel = uilabel(app.Panel_48);
            app.PRILabel.Text = {'脉冲周期：'; '(ms)'};
            app.PRI_tx = uieditfield(app.Panel_48, 'numeric');
            app.PRI_tx.Value = 2;
            app.PRILabel_2 = uilabel(app.Panel_48);
            app.PRILabel_2.Text = {'脉冲宽度：'; '（us)'};
            app.pulsewidth = uieditfield(app.Panel_48, 'numeric');
            app.pulsewidth.Value = 200;
            app.CheckBox_10 = uicheckbox(app.Panel_48);
            app.CheckBox_10.ValueChangedFcn = createCallbackFcn(app, @CheckBox_10ValueChanged, true);
            app.CheckBox_10.Text = '脉冲调制源';
            app.Label_107 = uilabel(app.Panel_43);
            app.Label_107.Text = {'衰减值'; '(dB)'};
            app.dBEditField = uieditfield(app.Panel_43, 'numeric');
            app.Label_128 = uilabel(app.Panel_43);
            app.Label_128.Text = {'分频'; '系数'};
            app.EditField_22 = uieditfield(app.Panel_43, 'numeric');
            app.EditField_22.Value = 2;
            app.dBEditField_7Label = uilabel(app.Panel_43);
            app.dBEditField_7Label.Text = '衰减值(dB)';
            app.dBEditField_7 = uieditfield(app.Panel_43, 'numeric');
            app.dBEditField_7.Limits = [0 35];
            app.CheckBox_16 = uicheckbox(app.Panel_43);
            app.CheckBox_16.ValueChangedFcn = createCallbackFcn(app, @CheckBox_16ValueChanged, true);
            app.CheckBox_16.Text = '内部';
            app.CheckBox_17 = uicheckbox(app.Panel_43);
            app.CheckBox_17.ValueChangedFcn = createCallbackFcn(app, @CheckBox_17ValueChanged, true);
            app.CheckBox_17.Text = '外部';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '矢量信号源';
            app.Panel_50 = uipanel(app.Tab_3);
            app.Image7 = uiimage(app.Panel_50);
            app.Image7.ImageSource = fullfile(pathToMLAPP, '矢量源1105.png');
            app.Panel_51 = uipanel(app.Panel_50);
            app.Panel_51.Title = '硬件平台设置';
            app.DropDown_7Label = uilabel(app.Panel_51);
            app.DropDown_7Label.Text = '发射通道：';
            app.DropDown_7 = uidropdown(app.Panel_51);
            app.DropDown_7.Items = {'       通道1', '       通道2', '  通道1和通道2'};
            app.DropDown_7.Value = '       通道1';
            app.Label_109 = uilabel(app.Panel_51);
            app.Label_109.Text = '仪器连接';
            app.Button_starttransmit_4 = uiswitch(app.Panel_51, 'slider');
            app.Button_starttransmit_4.Items = {'关', '开'};
            app.Button_starttransmit_4.ValueChangedFcn = createCallbackFcn(app, @Button_starttransmit_4ValueChanged, true);
            app.Button_starttransmit_4.Value = '关';
            app.ALCDropDown_2Label = uilabel(app.Panel_51);
            app.ALCDropDown_2Label.Text = 'ALC通道：';
            app.ALCDropDown_2 = uidropdown(app.Panel_51);
            app.ALCDropDown_2.Items = {'       通道1', '       通道2'};
            app.ALCDropDown_2.Value = '       通道1';
            app.Lamp_5 = uilamp(app.Panel_51);
            app.Button_46 = uibutton(app.Panel_51, 'push');
            app.Button_46.ButtonPushedFcn = createCallbackFcn(app, @Button_46Pushed, true);
            app.Button_46.Text = '波形下载';
            app.dBEditField_4Label = uilabel(app.Panel_50);
            app.dBEditField_4Label.Text = {'衰减值'; '(dB)'};
            app.dBEditField_4 = uieditfield(app.Panel_50, 'numeric');
            app.dBEditField_5Label = uilabel(app.Panel_50);
            app.dBEditField_5Label.Text = {'衰减值'; '(dB)'};
            app.dBEditField_5 = uieditfield(app.Panel_50, 'numeric');
            app.ALCCheckBox_2 = uicheckbox(app.Panel_50);
            app.ALCCheckBox_2.Text = {'启用'; 'ALC'};
            app.dBEditField_6Label = uilabel(app.Panel_50);
            app.dBEditField_6Label.Text = {'测量值'; '(dB)'};
            app.dBEditField_6 = uieditfield(app.Panel_50, 'numeric');
            app.Label_111 = uilabel(app.Panel_50);
            app.Label_111.Text = '滤波器类型';
            app.EditField_19 = uieditfield(app.Panel_50, 'text');
            app.EditField_19.Editable = 'off';
            app.EditField_19.Value = '根升余弦';
            app.Label_112 = uilabel(app.Panel_50);
            app.Label_112.Text = '滚降系数';
            app.rolloff = uieditfield(app.Panel_50, 'numeric');
            app.rolloff.Value = 0.35;
            app.Label_113 = uilabel(app.Panel_50);
            app.Label_113.Text = {'采样率'; 'MSa/s'};
            app.MSasEditField = uieditfield(app.Panel_50, 'numeric');
            app.MSasEditField.Editable = 'off';
            app.MSasEditField.Value = 200;
            app.Label_29 = uilabel(app.Panel_50);
            app.Label_29.Text = '调制方式：';
            app.DropDown_3 = uidropdown(app.Panel_50);
            app.DropDown_3.Items = {'     QPSK', '    16QAM'};
            app.DropDown_3.Value = '     QPSK';
            app.Label_28 = uilabel(app.Panel_50);
            app.Label_28.Text = '码元速率(Mbps)：';
            app.rb = uieditfield(app.Panel_50, 'numeric');
            app.rb.Limits = [0 Inf];
            app.rb.ValueDisplayFormat = '%.0f';
            app.rb.Value = 3;
            app.ButtonGroup_2 = uibuttongroup(app.Panel_50);
            app.Button_39 = uiradiobutton(app.ButtonGroup_2);
            app.Button_39.Text = '码型1';
            app.Button_39.Value = true;
            app.Button_40 = uiradiobutton(app.ButtonGroup_2);
            app.Button_40.Text = '码型2';
            app.Button_41 = uiradiobutton(app.ButtonGroup_2);
            app.Button_41.Text = '码型3';
            app.EditField_23Label = uilabel(app.Panel_50);
            app.EditField_23Label.Text = {'分频'; '系数'};
            app.EditField_23 = uieditfield(app.Panel_50, 'numeric');
            app.EditField_23.Value = 2;
            app.CheckBox_18 = uicheckbox(app.Panel_50);
            app.CheckBox_18.ValueChangedFcn = createCallbackFcn(app, @CheckBox_18ValueChanged, true);
            app.CheckBox_18.Text = '内部';
            app.CheckBox_19 = uicheckbox(app.Panel_50);
            app.CheckBox_19.Text = '外部';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '任意波及函数发生器';
            app.Panel_52 = uipanel(app.Tab_6);
            app.Image8 = uiimage(app.Panel_52);
            app.Image8.ImageSource = fullfile(pathToMLAPP, '任意波-V1.png');
            app.CheckBox_12 = uicheckbox(app.Panel_52);
            app.CheckBox_12.ValueChangedFcn = createCallbackFcn(app, @CheckBox_12ValueChanged, true);
            app.CheckBox_12.Text = '启用信号仿真';
            app.Panel_53 = uipanel(app.Panel_52);
            app.Panel_53.Title = '硬件平台设置';
            app.DropDown_9Label = uilabel(app.Panel_53);
            app.DropDown_9Label.Text = '发射通道：';
            app.DropDown_9 = uidropdown(app.Panel_53);
            app.DropDown_9.Items = {'       通道1', '       通道2'};
            app.DropDown_9.Value = '       通道1';
            app.Label_116 = uilabel(app.Panel_53);
            app.Label_116.Text = '数字射频信号产生';
            app.Button_starttransmit_5 = uiswitch(app.Panel_53, 'slider');
            app.Button_starttransmit_5.Items = {'关', '开'};
            app.Button_starttransmit_5.ValueChangedFcn = createCallbackFcn(app, @Button_starttransmit_5ValueChanged, true);
            app.Button_starttransmit_5.Value = '关';
            app.Lamp_6 = uilamp(app.Panel_53);
            app.MHzLabel_32 = uilabel(app.Panel_53);
            app.MHzLabel_32.Text = 'MHz';
            app.Label_52 = uilabel(app.Panel_53);
            app.Label_52.Text = '载频：';
            app.fc_11 = uieditfield(app.Panel_53, 'numeric');
            app.fc_11.Limits = [0 Inf];
            app.fc_11.ValueDisplayFormat = '%.2f';
            app.fc_11.Value = 10;
            app.Panel_56 = uipanel(app.Panel_52);
            app.Label_81 = uilabel(app.Panel_56);
            app.Label_81.Text = '波形类型：';
            app.DropDown_13 = uidropdown(app.Panel_56);
            app.DropDown_13.Items = {'正弦波', '余弦波', '三角波', '方波', '锯齿波'};
            app.DropDown_13.Value = '正弦波';
            app.CheckBox_13 = uicheckbox(app.Panel_56);
            app.CheckBox_13.ValueChangedFcn = createCallbackFcn(app, @CheckBox_13ValueChanged, true);
            app.CheckBox_13.Text = '启用函数仿真';
            app.Label_55 = uilabel(app.Panel_52);
            app.Label_55.Text = {'波形长度'; '（Msa）'};
            app.samples_3 = uieditfield(app.Panel_52, 'numeric');
            app.samples_3.Limits = [1 Inf];
            app.samples_3.ValueDisplayFormat = '%.0f';
            app.samples_3.Value = 512;
            app.Label_125 = uilabel(app.Panel_52);
            app.Label_125.Text = {'输出电压:'; '（Mv)'};
            app.lowpassfilter_2 = uieditfield(app.Panel_52, 'numeric');
            app.lowpassfilter_2.Value = 100;
            app.Label_126 = uilabel(app.Panel_52);
            app.Label_126.Text = {'采样率：'; '(Msa)'};
            app.lowpassfilter_3 = uieditfield(app.Panel_52, 'numeric');
            app.lowpassfilter_3.Value = 100;
            app.TabGroup2 = uitabgroup(app.Panel_52);
            app.Tab_7 = uitab(app.TabGroup2);
            app.Tab_7.Title = '噪声';
            app.MHzLabel_33 = uilabel(app.Tab_7);
            app.MHzLabel_33.Text = 'MHz';
            app.Label_53 = uilabel(app.Tab_7);
            app.Label_53.Text = '带宽：';
            app.fc_12 = uieditfield(app.Tab_7, 'numeric');
            app.fc_12.Limits = [0 Inf];
            app.fc_12.ValueDisplayFormat = '%.2f';
            app.fc_12.Value = 10;
            app.Tab_8 = uitab(app.TabGroup2);
            app.Tab_8.Title = '多音信号';
            app.MHzLabel_29 = uilabel(app.Tab_8);
            app.MHzLabel_29.Text = 'MHz';
            app.Label_49 = uilabel(app.Tab_8);
            app.Label_49.Text = '起始频偏：';
            app.startfd = uieditfield(app.Tab_8, 'numeric');
            app.startfd.Limits = [0 Inf];
            app.startfd.ValueDisplayFormat = '%.2f';
            app.startfd.Value = 1;
            app.Label_50 = uilabel(app.Tab_8);
            app.Label_50.Text = '终止频偏：';
            app.endfd = uieditfield(app.Tab_8, 'numeric');
            app.endfd.Limits = [1e-06 Inf];
            app.endfd.ValueDisplayFormat = '%.2f';
            app.endfd.Value = 3;
            app.MHzLabel_30 = uilabel(app.Tab_8);
            app.MHzLabel_30.Text = 'MHz';
            app.Label_51 = uilabel(app.Tab_8);
            app.Label_51.Text = '多音数目：';
            app.tonenum = uieditfield(app.Tab_8, 'numeric');
            app.tonenum.Limits = [2 10];
            app.tonenum.ValueDisplayFormat = '%.0f';
            app.tonenum.Value = 3;
            app.Tab_9 = uitab(app.TabGroup2);
            app.Tab_9.Title = '脉冲';
            app.msLabel_2 = uilabel(app.Tab_9);
            app.msLabel_2.Text = 'ms';
            app.Label_65 = uilabel(app.Tab_9);
            app.Label_65.Text = 'PRI：';
            app.pri1 = uieditfield(app.Tab_9, 'numeric');
            app.pri1.Limits = [1e-06 999.9999999];
            app.pri1.ValueDisplayFormat = '%.0f';
            app.pri1.Value = 3;
            app.Label_66 = uilabel(app.Tab_9);
            app.Label_66.Text = '脉宽：';
            app.pulsewidth1 = uieditfield(app.Tab_9, 'numeric');
            app.pulsewidth1.Limits = [1 Inf];
            app.pulsewidth1.ValueDisplayFormat = '%.2f';
            app.pulsewidth1.Value = 3;
            app.usLabel_4 = uilabel(app.Tab_9);
            app.usLabel_4.Text = 'us';
            app.FMCWTab = uitab(app.TabGroup2);
            app.FMCWTab.Title = 'FMCW';
            app.msLabel_3 = uilabel(app.FMCWTab);
            app.msLabel_3.Text = 'ms';
            app.MHzLabel_43 = uilabel(app.FMCWTab);
            app.MHzLabel_43.Text = 'MHz';
            app.Label_69 = uilabel(app.FMCWTab);
            app.Label_69.Text = '扫描时间：';
            app.sweeptime = uieditfield(app.FMCWTab, 'numeric');
            app.sweeptime.Limits = [0 Inf];
            app.sweeptime.ValueDisplayFormat = '%.0f';
            app.sweeptime.Value = 3;
            app.Label_70 = uilabel(app.FMCWTab);
            app.Label_70.Text = '扫描宽度：';
            app.rolloff_5 = uieditfield(app.FMCWTab, 'numeric');
            app.rolloff_5.Limits = [0.001 60];
            app.rolloff_5.ValueDisplayFormat = '%.2f';
            app.rolloff_5.Value = 0.5;
            app.BPSKTab = uitab(app.TabGroup2);
            app.BPSKTab.Title = 'BPSK';
            app.MBdLabel_3 = uilabel(app.BPSKTab);
            app.MBdLabel_3.Text = 'MBd';
            app.Label_61 = uilabel(app.BPSKTab);
            app.Label_61.Text = '码元速率：';
            app.rb_3 = uieditfield(app.BPSKTab, 'numeric');
            app.rb_3.Limits = [0 Inf];
            app.rb_3.ValueDisplayFormat = '%.0f';
            app.rb_3.Value = 3;
            app.Label_62 = uilabel(app.BPSKTab);
            app.Label_62.Text = '滚降系数：';
            app.rolloff_3 = uieditfield(app.BPSKTab, 'numeric');
            app.rolloff_3.Limits = [0.001 0.9999];
            app.rolloff_3.ValueDisplayFormat = '%.2f';
            app.rolloff_3.Value = 0.35;
            app.Tab_15 = uitab(app.TabGroup2);
            app.Tab_15.Title = 'Tab';
            app.CheckBox_5 = uicheckbox(app.Panel_52);
            app.CheckBox_5.Text = '启用';
            app.Label_95 = uilabel(app.Panel_52);
            app.Label_95.Text = {'低通带宽'; '（MHz)'};
            app.lowpassfilter = uieditfield(app.Panel_52, 'numeric');
            app.lowpassfilter.Value = 5;
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '频谱仪';
            app.Image7_2 = uiimage(app.Tab_5);
            app.Image7_2.ImageSource = fullfile(pathToMLAPP, '频谱分析2.png');
            app.Panel_54 = uipanel(app.Tab_5);
            app.Panel_54.Title = '硬件平台设置';
            app.Label_124 = uilabel(app.Panel_54);
            app.Label_124.Text = '接收通道：';
            app.DropDown_10 = uidropdown(app.Panel_54);
            app.DropDown_10.Items = {'       通道1', '       通道2'};
            app.DropDown_10.Value = '       通道1';
            app.Label_117 = uilabel(app.Panel_54);
            app.Label_117.Text = '频谱仪开关';
            app.Switch = uiswitch(app.Panel_54, 'slider');
            app.Switch.Items = {'关', '开'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '关';
            app.Lamp = uilamp(app.Panel_54);
            app.CheckBox_11 = uicheckbox(app.Tab_5);
            app.CheckBox_11.Text = '频谱分析';
            app.FFTEditFieldLabel = uilabel(app.Tab_5);
            app.FFTEditFieldLabel.Text = 'FFT点数';
            app.FFTEditField = uieditfield(app.Tab_5, 'numeric');
            app.FFTEditField.Limits = [0 Inf];
            app.FFTEditField.RoundFractionalValues = 'on';
            app.FFTEditField.Value = 8192;
            app.MHzEditField_3Label = uilabel(app.Tab_5);
            app.MHzEditField_3Label.Text = '中心频率(MHz)';
            app.MHzEditField_3 = uieditfield(app.Tab_5, 'numeric');
            app.MHzEditField_3.Limits = [0 Inf];
            app.MHzEditField_3.RoundFractionalValues = 'on';
            app.MHzEditField_3.Value = 20;
            app.Label_75 = uilabel(app.Tab_5);
            app.Label_75.Text = '解调方式：';
            app.DropDown_5 = uidropdown(app.Tab_5);
            app.DropDown_5.Items = {'QPSK', '16QAM'};
            app.DropDown_5.Value = 'QPSK';
            app.EditField_9Label_12 = uilabel(app.Tab_5);
            app.EditField_9Label_12.Text = '码元速率(MHz)：';
            app.coderate = uieditfield(app.Tab_5, 'numeric');
            app.coderate.Value = 1;
            app.Label_77 = uilabel(app.Tab_5);
            app.Label_77.Text = '滚降系数：';
            app.rolloff_6 = uieditfield(app.Tab_5, 'numeric');
            app.rolloff_6.Limits = [0.001 0.9999];
            app.rolloff_6.ValueDisplayFormat = '%.2f';
            app.rolloff_6.Value = 0.35;
            app.CheckBox_2 = uicheckbox(app.Tab_5);
            app.CheckBox_2.Text = '矢量解调分析';
            app.Label_99 = uilabel(app.Tab_5);
            app.Label_99.Text = '通道:';
            app.Channel = uidropdown(app.Tab_5);
            app.Channel.Items = {'DC-40MHz', '500-850MHz', '1100-1450MHz'};
            app.Channel.Value = 'DC-40MHz';
            app.Tab_10 = uitab(app.TabGroup);
            app.Tab_10.Title = '示波器及数字化仪';
            app.Image7_3 = uiimage(app.Tab_10);
            app.Image7_3.ImageSource = fullfile(pathToMLAPP, '示波器2.png');
            app.Panel_55 = uipanel(app.Tab_10);
            app.Panel_55.Title = '硬件平台设置';
            app.Label_123 = uilabel(app.Panel_55);
            app.Label_123.Text = '接收通道：';
            app.DropDown_11 = uidropdown(app.Panel_55);
            app.DropDown_11.Items = {'       通道1', '       通道2'};
            app.DropDown_11.Value = '       通道1';
            app.Label_118 = uilabel(app.Panel_55);
            app.Label_118.Text = '示波器开关';
            app.Switch_2 = uiswitch(app.Panel_55, 'slider');
            app.Switch_2.Items = {'关', '开'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = '关';
            app.Lamp_2 = uilamp(app.Panel_55);
            app.Label_119 = uilabel(app.Tab_10);
            app.Label_119.Text = '触发类型:';
            app.DropDown_12 = uidropdown(app.Tab_10);
            app.DropDown_12.Items = {'edge'};
            app.DropDown_12.Value = 'edge';
            app.Label_120 = uilabel(app.Tab_10);
            app.Label_120.Text = '触发电平:';
            app.EditField_20 = uieditfield(app.Tab_10, 'numeric');
            app.EditField_20.Value = 0.3;
            app.usEditFieldLabel = uilabel(app.Tab_10);
            app.usEditFieldLabel.Text = '时延(us):';
            app.usEditField = uieditfield(app.Tab_10, 'numeric');
            app.Label_87 = uilabel(app.Tab_10);
            app.Label_87.Text = '存储路径：';
            app.savepath = uieditfield(app.Tab_10, 'text');
            app.savepath.Value = 'C:\Users\admin\Desktop\we\data.bin';
            app.Label_121 = uilabel(app.Tab_10);
            app.Label_121.Text = {'采样率：'; '(Mbps)'};
            app.fsam_rx_4 = uieditfield(app.Tab_10, 'numeric');
            app.fsam_rx_4.ValueDisplayFormat = '%.2f';
            app.fsam_rx_4.Value = 200;
            app.fsam_rx_5 = uieditfield(app.Tab_10, 'numeric');
            app.fsam_rx_5.ValueDisplayFormat = '%.2f';
            app.fsam_rx_5.Editable = 'off';
            app.fsam_rx_5.Value = 512;
            app.MSaLabel = uilabel(app.Tab_10);
            app.MSaLabel.Text = {'波形长度'; '（MSa）'};
            app.Label_122 = uilabel(app.Tab_10);
            app.Label_122.Text = {'时基：'; 'ms/div'};
            app.fsam_rx_6 = uieditfield(app.Tab_10, 'numeric');
            app.fsam_rx_6.ValueDisplayFormat = '%.2f';
            app.fsam_rx_6.Value = 2;
            app.Channel_2 = uidropdown(app.Tab_10);
            app.Channel_2.Items = {'DC-40MHz', '500-850MHz', '1100-1450MHz'};
            app.Channel_2.Value = 'DC-40MHz';
            app.Label_127 = uilabel(app.Tab_10);
            app.Label_127.Text = '通道:';
            app.CheckBox_14 = uicheckbox(app.Tab_10);
            app.CheckBox_14.Text = '保存波形';
            app.Tab_12 = uitab(app.TabGroup);
            app.Tab_12.Title = '衰减器控制';
            app.Panel_38 = uipanel(app.Tab_12);
            app.Panel_38.Title = 'Panel';
            app.opendevice = uibutton(app.Panel_38, 'push');
            app.opendevice.ButtonPushedFcn = createCallbackFcn(app, @opendeviceButtonPushed2, true);
            app.opendevice.Text = '打开设备';
            app.closedevice = uibutton(app.Panel_38, 'push');
            app.closedevice.ButtonPushedFcn = createCallbackFcn(app, @closedeviceButtonPushed, true);
            app.closedevice.Enable = 'off';
            app.closedevice.Text = '关闭设备';
            app.Panel_39 = uipanel(app.Tab_12);
            app.Panel_39.Title = '衰减设置';
            app.Button_30 = uibutton(app.Panel_39, 'push');
            app.Button_30.ButtonPushedFcn = createCallbackFcn(app, @Button_30Pushed2, true);
            app.Button_30.Text = '设置';
            app.dBLabel_7 = uilabel(app.Panel_39);
            app.dBLabel_7.Text = 'dB';
            app.Label_98 = uilabel(app.Panel_39);
            app.Label_98.Text = '衰减量(dB):';
            app.Attenuation = uidropdown(app.Panel_39);
            app.Attenuation.Items = {'0.25', '0.5', '1', '2', '4', '8', '16', '32'};
            app.Attenuation.Value = '16';
            app.Panel_40 = uipanel(app.Tab_12);
            app.Panel_40.Title = '日志输出';
            app.logarea = uitextarea(app.Panel_40);
            app.logarea.Editable = 'off';
            app.Panel_41 = uipanel(app.Tab_12);
            app.Panel_41.Title = '接收控制';
            app.Button_36 = uibutton(app.Panel_41, 'push');
            app.Button_36.ButtonPushedFcn = createCallbackFcn(app, @Button_36Pushed, true);
            app.Button_36.Text = '设置';
            app.Button_42 = uibutton(app.Tab_12, 'push');
            app.Button_42.ButtonPushedFcn = createCallbackFcn(app, @Button_42Pushed, true);
            app.Tab_13 = uitab(app.TabGroup);
            app.Tab_13.Title = '关于';
            app.Panel_42 = uipanel(app.Tab_13);
            app.TextArea = uitextarea(app.Panel_42);
            app.TextArea.Value = {'SDR接口调用'; '1.	发射接口'; 'comm.SDRuTransmitter( ''Platform'', app.Platform, ...   '; '                    ''SerialNum'', app.SerialNum, ... % 使用检测到的设备'; '                    ''CenterFrequency'', app.fc, ...   %载频'; '                    ''Gain'',70-app.TxGain , ...       %增益'; '                    "ChannelMapping",app.TXchannel,...  %使用的通道'; '                    ''InterpolationFactor'', InterpolationFactor, ... %，插值因子'; '                    ''MasterClockRate'', app.MasterClockRate);    % 采样率'; '2.	接收接口'; 'comm.SDRuReceiver( ''Platform'', app.Platform, ...'; '                    ''SerialNum'', app.SerialNum, ... % 使用检测到的设备'; '                    ''CenterFrequency'', app.fc, ...'; '                    ''Gain'',app.RxGain , ...'; '                    "ChannelMapping",app.RXchannel,...'; '                    OutputDataType=''double'',...      %输出数据类型'; '                    DecimationFactor= DecimationFactor,...   %抽取因子'; '                    SamplesPerFrame=app.samplesPerFrame,...  %每帧点数'; '                    MasterClockRate= app.MasterClockRate);    % 采样率'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; 'SDR接口调用'; '1.	发射接口'; 'comm.SDRuTransmitter( ''Platform'', app.Platform, ...   '; '                    ''SerialNum'', app.SerialNum, ... % 使用检测到的设备'; '                    ''CenterFrequency'', app.fc, ...   %载频'; '                    ''Gain'',70-app.TxGain , ...       %增益'; '                    "ChannelMapping",app.TXchannel,...  %使用的通道'; '                    ''InterpolationFactor'', InterpolationFactor, ... %，插值因子'; '                    ''MasterClockRate'', app.MasterClockRate);    % 采样率'; '2.	接收接口'; 'comm.SDRuReceiver( ''Platform'', app.Platform, ...'; '                    ''SerialNum'', app.SerialNum, ... % 使用检测到的设备'; '                    ''CenterFrequency'', app.fc, ...'; '                    ''Gain'',app.RxGain , ...'; '                    "ChannelMapping",app.RXchannel,...'; '                    OutputDataType=''double'',...      %输出数据类型'; '                    DecimationFactor= DecimationFactor,...   %抽取因子'; '                    SamplesPerFrame=app.samplesPerFrame,...  %每帧点数'; '                    MasterClockRate= app.MasterClockRate);    % 采样率'; ''; ''};
            app.Tab_14 = uitab(app.TabGroup);
            app.Tab_14.Title = 'Tab';
            app.Panel_44 = uipanel(app.Tab_14);
            app.Button_37 = uibutton(app.Panel_44, 'push');
            app.Button_37.ButtonPushedFcn = createCallbackFcn(app, @Button_37Pushed, true);
            app.Button_37.Text = '发送';
            app.EditField_17Label = uilabel(app.Panel_44);
            app.EditField_17Label.Text = '客户端地址';
            app.EditField_17 = uieditfield(app.Panel_44, 'text');
            app.binEditFieldLabel = uilabel(app.Panel_44);
            app.binEditFieldLabel.Text = 'bin文件地址';
            app.binEditField = uieditfield(app.Panel_44, 'text');
            app.binEditField.Value = 'C:\Users\admin\Desktop\共享文件夹\sdr_iq_data.bin';
            app.IPLabel = uilabel(app.Panel_44);
            app.IPLabel.Text = 'IP地址：';
            app.IPEditField_2 = uieditfield(app.Panel_44, 'text');
            app.IPEditField_2.Value = '192.168.1.111';
            app.Button_38 = uibutton(app.Panel_44, 'push');
            app.Button_38.ButtonPushedFcn = createCallbackFcn(app, @Button_38Pushed, true);
            app.Button_38.Text = '打开';
            app.Label_106 = uilabel(app.Panel_44);
            app.Label_106.Text = '端口号：';
            app.EditField_16 = uieditfield(app.Panel_44, 'numeric');
            app.EditField_16.Value = 8931;
            app.EditField_18Label = uilabel(app.Panel_44);
            app.EditField_18Label.Text = '端口号：';
            app.EditField_18 = uieditfield(app.Panel_44, 'numeric');
            app.Lamp_4 = uilamp(app.Panel_44);
            app.Panel_57 = uipanel(app.Tab_14);
            app.Button_43 = uibutton(app.Panel_57, 'push');
            app.Button_43.Text = '发送';
            app.EditField_24Label = uilabel(app.Panel_57);
            app.EditField_24Label.Text = '客户端地址';
            app.EditField_24 = uieditfield(app.Panel_57, 'text');
            app.binEditField_2Label = uilabel(app.Panel_57);
            app.binEditField_2Label.Text = 'bin文件地址';
            app.binEditField_2 = uieditfield(app.Panel_57, 'text');
            app.binEditField_2.Value = 'C:\Users\admin\Desktop\共享文件夹\sdr_iq_data.bin';
            app.IPEditField_3Label = uilabel(app.Panel_57);
            app.IPEditField_3Label.Text = 'IP地址：';
            app.IPEditField_3 = uieditfield(app.Panel_57, 'text');
            app.IPEditField_3.Value = '192.168.1.111';
            app.Button_44 = uibutton(app.Panel_57, 'push');
            app.Button_44.Text = '打开';
            app.EditField_25Label = uilabel(app.Panel_57);
            app.EditField_25Label.Text = '端口号：';
            app.EditField_25 = uieditfield(app.Panel_57, 'numeric');
            app.EditField_25.Value = 8931;
            app.EditField_26Label = uilabel(app.Panel_57);
            app.EditField_26Label.Text = '端口号：';
            app.EditField_26 = uieditfield(app.Panel_57, 'numeric');
            app.Lamp_7 = uilamp(app.Panel_57);
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_5 = uipanel(app.Panel_4);
            app.Panel_5.Title = '频率合成模块';
            app.MHzLabel = uilabel(app.Panel_5);
            app.MHzLabel.Text = 'MHz';
            app.EditField_9Label_3 = uilabel(app.Panel_5);
            app.EditField_9Label_3.Text = '数字上变频：';
            app.SDRFc_tx = uieditfield(app.Panel_5, 'numeric');
            app.SDRFc_tx.Value = 2000;
            app.ButtonGroup_tx = uibuttongroup(app.Panel_4);
            app.ButtonGroup_tx.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_txSelectionChanged, true);
            app.ButtonGroup_tx.Title = '模拟调制模块';
            app.AMButton_tx = uiradiobutton(app.ButtonGroup_tx);
            app.AMButton_tx.Text = '调幅';
            app.AMButton_tx.Value = true;
            app.FMButton_tx = uiradiobutton(app.ButtonGroup_tx);
            app.FMButton_tx.Text = '调频';
            app.PMButton_tx = uiradiobutton(app.ButtonGroup_tx);
            app.PMButton_tx.Text = '脉冲';
            app.CMButton = uiradiobutton(app.ButtonGroup_tx);
            app.CMButton.Text = '连续波';
            app.Panel_8 = uipanel(app.Panel_4);
            app.Panel_8.Title = '硬件平台设置';
            app.Button_14 = uibutton(app.Panel_8, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '获取';
            app.Label_9 = uilabel(app.Panel_8);
            app.Label_9.Text = '发射信号采样率：';
            app.masterclock = uieditfield(app.Panel_8, 'numeric');
            app.masterclock.Limits = [0 Inf];
            app.masterclock.ValueDisplayFormat = '%.2f';
            app.masterclock.Value = 200;
            app.MsasLabel = uilabel(app.Panel_8);
            app.MsasLabel.Text = 'Msa/s';
            app.EditField_9Label_2 = uilabel(app.Panel_8);
            app.EditField_9Label_2.Text = '地址：';
            app.SDRAddress_tx = uieditfield(app.Panel_8, 'text');
            app.MsaLabel = uilabel(app.Panel_8);
            app.MsaLabel.Text = 'Msa';
            app.Label_104 = uilabel(app.Panel_8);
            app.Label_104.Text = '信号长度：';
            app.TxDuration = uieditfield(app.Panel_8, 'numeric');
            app.TxDuration.Limits = [0 Inf];
            app.TxDuration.Value = 512;
            app.AMPanel_4 = uipanel(app.Panel_4);
            app.AMPanel_4.Title = 'AM信号参数配置';
            app.MHzLabel_3 = uilabel(app.AMPanel_4);
            app.MHzLabel_3.Text = 'MHz';
            app.Label_10 = uilabel(app.AMPanel_4);
            app.Label_10.Text = '信号采样率：';
            app.EditField_amfs = uieditfield(app.AMPanel_4, 'numeric');
            app.EditField_amfs.Value = 10;
            app.Panel_6 = uipanel(app.Panel_4);
            app.Panel_6.Title = '功率控制模块';
            app.EditField_9Label_5 = uilabel(app.Panel_6);
            app.EditField_9Label_5.Text = '数字衰减：';
            app.TXgain = uieditfield(app.Panel_6, 'numeric');
            app.dBLabel = uilabel(app.Panel_6);
            app.dBLabel.Text = 'dB';
            app.dBmLabel_4 = uilabel(app.Panel_6);
            app.dBmLabel_4.Text = '最大输出0dBm';
            app.dBmLabel = uilabel(app.Panel_6);
            app.dBmLabel.Text = 'dBm';
            app.dBmLabel_2 = uilabel(app.Panel_6);
            app.dBmLabel_2.Text = 'dBm';
            app.dBmLabel_3 = uilabel(app.Panel_6);
            app.dBmLabel_3.Text = 'dBm';
            app.Label_6 = uilabel(app.Panel_6);
            app.Label_6.Text = '起始衰减：';
            app.EditField = uieditfield(app.Panel_6, 'numeric');
            app.Label_7 = uilabel(app.Panel_6);
            app.Label_7.Text = '终止衰减：';
            app.EditField_2 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_2.Value = 20;
            app.Label_8 = uilabel(app.Panel_6);
            app.Label_8.Text = '衰减步进：';
            app.EditField_3 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_3.Value = 5;
            app.Label_94 = uilabel(app.Panel_6);
            app.Label_94.Text = '功率扫描设置';
            app.CheckBox_3 = uicheckbox(app.Panel_6);
            app.CheckBox_3.Text = '启用';
            app.Panel_18 = uipanel(app.UIFigure);
            app.Panel_18.Title = '平台设置';
            app.Button_27 = uibutton(app.Panel_18, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Button_27Pushed, true);
            app.Button_27.Text = '获取';
            app.SDR_4 = uilabel(app.Panel_18);
            app.SDR_4.Text = '地址：';
            app.SDRPlatform_rx_3 = uitextarea(app.Panel_18);
            app.MHzLabel_35 = uilabel(app.Panel_18);
            app.MHzLabel_35.Text = 'MHz';
            app.Label_57 = uilabel(app.Panel_18);
            app.Label_57.Text = '中心频率：';
            app.fs_7 = uieditfield(app.Panel_18, 'numeric');
            app.fs_7.Limits = [0.01 7000];
            app.fs_7.ValueDisplayFormat = '%.2f';
            app.fs_7.Value = 2000;
            app.Panel_20 = uipanel(app.UIFigure);
            app.Panel_20.Title = '数字化仪采集设置';
            app.Button_28 = uibutton(app.Panel_20, 'push');
            app.Button_28.ButtonPushedFcn = createCallbackFcn(app, @Button_28Pushed, true);
            app.Button_28.Enable = 'off';
            app.Button_28.Text = '采集';
            app.sLabel_11 = uilabel(app.Panel_20);
            app.sLabel_11.Text = 's';
            app.Label_86 = uilabel(app.Panel_20);
            app.Label_86.Text = '采集时长：';
            app.samples_7 = uieditfield(app.Panel_20, 'numeric');
            app.samples_7.Limits = [0 Inf];
            app.samples_7.ValueDisplayFormat = '%.3f';
            app.samples_7.Value = 0.1;
            app.Panel_29 = uipanel(app.UIFigure);
            app.Panel_29.Title = '数字衰减模块';
            app.EditField_9Label_11 = uilabel(app.Panel_29);
            app.EditField_9Label_11.Text = '数字衰减：';
            app.RXgain_3 = uieditfield(app.Panel_29, 'numeric');
            app.dBLabel_6 = uilabel(app.Panel_29);
            app.dBLabel_6.Text = 'dB';
            app.Panel_30 = uipanel(app.UIFigure);
            app.Panel_30.Title = '振荡模块';
            app.Label_56 = uilabel(app.Panel_30);
            app.Label_56.Text = '采样时钟频率：';
            app.fsam_rx_3 = uieditfield(app.Panel_30, 'numeric');
            app.fsam_rx_3.ValueDisplayFormat = '%.2f';
            app.fsam_rx_3.Value = 200;
            app.MHzLabel_34 = uilabel(app.Panel_30);
            app.MHzLabel_34.Text = 'MHz';
            app.Panel_31 = uipanel(app.UIFigure);
            app.Panel_31.Title = '信号处理模块';
            app.Label_59 = uilabel(app.Panel_31);
            app.Label_59.Text = '帧长：';
            app.samples_4 = uieditfield(app.Panel_31, 'numeric');
            app.samples_4.Limits = [1 Inf];
            app.samples_4.ValueDisplayFormat = '%.0f';
            app.samples_4.Value = 20000;
            app.samplesLabel_4 = uilabel(app.Panel_31);
            app.samplesLabel_4.Text = 'samples';
            app.Panel_37 = uipanel(app.UIFigure);
            app.Panel_37.Title = '数字滤波模块';
            app.MHzLabel_48 = uilabel(app.Panel_37);
            app.MHzLabel_48.Text = 'MHz';
            app.Label_96 = uilabel(app.Panel_37);
            app.Label_96.Text = '低通滤波带宽';
            app.EditField_15 = uieditfield(app.Panel_37, 'numeric');
            app.EditField_15.Value = 5;
            app.CheckBox_4 = uicheckbox(app.Panel_37);
            app.CheckBox_4.Text = '启用';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.MHzLabel_45 = uilabel(app.UIFigure);
            app.MHzLabel_45.Text = 'MHz';
            app.Label_82 = uilabel(app.UIFigure);
            app.Label_82.Text = '频率：';
            app.f = uieditfield(app.UIFigure, 'numeric');
            app.f.Limits = [1 Inf];
            app.f.ValueDisplayFormat = '%.0f';
            app.f.Value = 1000;
            app.Label_83 = uilabel(app.UIFigure);
            app.Label_83.Text = '幅度：';
            app.samples_6 = uieditfield(app.UIFigure, 'numeric');
            app.samples_6.Limits = [1 Inf];
            app.samples_6.ValueDisplayFormat = '%.0f';
            app.samples_6.Value = 1;
            app.Label_88 = uilabel(app.UIFigure);
            app.Label_88.Text = '载频：';
            app.Fc_6 = uieditfield(app.UIFigure, 'numeric');
            app.Fc_6.Limits = [0 Inf];
            app.Fc_6.ValueDisplayFormat = '%.0f';
            app.Fc_6.Value = 2000;
            app.Label_105 = uilabel(app.UIFigure);
            app.Label_105.Text = '采样率：';
            app.fs_12 = uieditfield(app.UIFigure, 'numeric');
            app.fs_12.Limits = [5 61.44];
            app.fs_12.ValueDisplayFormat = '%.2f';
            app.fs_12.Value = 10;
            app.MHzLabel_49 = uilabel(app.UIFigure);
            app.MHzLabel_49.Text = 'MHz';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged2, true);
            app.ButtonGroup.Title = '数字射频滤波';
            app.ButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ButtonGroupButtonDown, true);
            app.Button_31 = uiradiobutton(app.ButtonGroup);
            app.Button_31.Text = '低通';
            app.Button_31.Value = true;
            app.Button_32 = uiradiobutton(app.ButtonGroup);
            app.Button_32.Text = '带通1';
            app.Button_33 = uiradiobutton(app.ButtonGroup);
            app.Button_33.Text = '带通2';
            app.EditField_21Label = uilabel(app.UIFigure);
            app.EditField_21Label.Text = 'Edit Field';
            app.EditField_21 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_21.Value = 20;
            app.GPIOPanel = uipanel(app.UIFigure);
            app.GPIOPanel.Title = 'GPIO';
            app.Label_97 = uilabel(app.GPIOPanel);
            app.Label_97.Text = '使能';
            app.outLabel = uilabel(app.GPIOPanel);
            app.outLabel.Text = '方向(out为选中)';
            app.HLabel = uilabel(app.GPIOPanel);
            app.HLabel.Text = '电平(H为选中)';
            app.Gpio0CheckBox = uicheckbox(app.GPIOPanel);
            app.Gpio0CheckBox.Text = 'Gpio0';
            app.Gpio1CheckBox = uicheckbox(app.GPIOPanel);
            app.Gpio1CheckBox.Text = 'Gpio1';
            app.Gpio2CheckBox = uicheckbox(app.GPIOPanel);
            app.Gpio2CheckBox.Text = 'Gpio2';
            app.Gpio3CheckBox = uicheckbox(app.GPIOPanel);
            app.Gpio3CheckBox.Text = 'Gpio3';
            app.Gpio4CheckBox = uicheckbox(app.GPIOPanel);
            app.Gpio4CheckBox.Text = 'Gpio4';
            app.Gpio5CheckBox = uicheckbox(app.GPIOPanel);
            app.Gpio5CheckBox.Text = 'Gpio5';
            app.Gpio6CheckBox = uicheckbox(app.GPIOPanel);
            app.Gpio6CheckBox.Text = 'Gpio6';
            app.Gpio7CheckBox = uicheckbox(app.GPIOPanel);
            app.Gpio7CheckBox.Text = 'Gpio7';
            app.Gpioallselect = uicheckbox(app.GPIOPanel);
            app.Gpioallselect.Text = '全选';
            app.OutInCheckBox = uicheckbox(app.GPIOPanel);
            app.OutInCheckBox.Text = 'Out/In';
            app.OutInCheckBox_2 = uicheckbox(app.GPIOPanel);
            app.OutInCheckBox_2.Text = 'Out/In';
            app.OutInCheckBox_3 = uicheckbox(app.GPIOPanel);
            app.OutInCheckBox_3.Text = 'Out/In';
            app.OutInCheckBox_4 = uicheckbox(app.GPIOPanel);
            app.OutInCheckBox_4.Text = 'Out/In';
            app.OutInCheckBox_5 = uicheckbox(app.GPIOPanel);
            app.OutInCheckBox_5.Text = 'Out/In';
            app.OutInCheckBox_6 = uicheckbox(app.GPIOPanel);
            app.OutInCheckBox_6.Text = 'Out/In';
            app.OutInCheckBox_7 = uicheckbox(app.GPIOPanel);
            app.OutInCheckBox_7.Text = 'Out/In';
            app.OutInCheckBox_8 = uicheckbox(app.GPIOPanel);
            app.OutInCheckBox_8.Text = 'Out/In';
            app.directionallselect = uicheckbox(app.GPIOPanel);
            app.directionallselect.Text = '全选';
            app.HLCheckBox = uicheckbox(app.GPIOPanel);
            app.HLCheckBox.Text = 'H/L';
            app.HLCheckBox_2 = uicheckbox(app.GPIOPanel);
            app.HLCheckBox_2.Text = 'H/L';
            app.HLCheckBox_3 = uicheckbox(app.GPIOPanel);
            app.HLCheckBox_3.Text = 'H/L';
            app.HLCheckBox_4 = uicheckbox(app.GPIOPanel);
            app.HLCheckBox_4.Text = 'H/L';
            app.HLCheckBox_5 = uicheckbox(app.GPIOPanel);
            app.HLCheckBox_5.Text = 'H/L';
            app.HLCheckBox_6 = uicheckbox(app.GPIOPanel);
            app.HLCheckBox_6.Text = 'H/L';
            app.HLCheckBox_7 = uicheckbox(app.GPIOPanel);
            app.HLCheckBox_7.Text = 'H/L';
            app.HLCheckBox_8 = uicheckbox(app.GPIOPanel);
            app.HLCheckBox_8.Text = 'H/L';
            app.HLallselect = uicheckbox(app.GPIOPanel);
            app.HLallselect.Text = '全选';
            app.Button_34 = uibutton(app.GPIOPanel, 'push');
            app.Button_34.Text = '设置';
            app.Lamp_8Label = uilabel(app.UIFigure);
            app.Lamp_8Label.Text = 'Lamp';
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Label_129 = uilabel(app.UIFigure);
            app.Label_129.Text = '网络连接状态';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'未连接'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signalgenerator_12_13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end