classdef project2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        UIAxes       matlab.ui.control.UIAxes
        ContextMenu  matlab.ui.container.ContextMenu
        sinMenu      matlab.ui.container.Menu
        cosMenu      matlab.ui.container.Menu
        Menu         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        IsClear=false; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: sinMenu
        function sinMenuSelected(app, event)
            fs=1e7;
            t=(0:1000-1)/fs;
            y=cos(2*pi*1e6*t);
            aa=app.IsClear;
            app.IsClear=false;
            while ~app.IsClear
                plot(app.UIAxes, t, y, 'b-', 'LineWidth', 2);
                pause(0.1);
            end
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            app.IsClear=true;
            % pause(2);
            cla(app.UIAxes);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.sinMenu = uimenu(app.ContextMenu);
            app.sinMenu.MenuSelectedFcn = createCallbackFcn(app, @sinMenuSelected, true);
            app.sinMenu.Text = 'sin';
            app.cosMenu = uimenu(app.ContextMenu);
            app.cosMenu.Text = 'cos';
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '清除';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end